classdef celltracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Slider_Frame              matlab.ui.control.Slider
        FrameLabel                matlab.ui.control.Label
        Text02                    matlab.ui.control.Label
        Text01                    matlab.ui.control.Label
        WholeImduplicatePanel     matlab.ui.container.Panel
        Button_WID_Forward        matlab.ui.control.Button
        Button_WID_Reverse        matlab.ui.control.Button
        TemplateMatchingPanel     matlab.ui.container.Panel
        Button_TM_Forward         matlab.ui.control.Button
        Button_TM_Reverse         matlab.ui.control.Button
        AdjustPanel               matlab.ui.container.Panel
        EditField_Outer           matlab.ui.control.NumericEditField
        OuterLabel                matlab.ui.control.Label
        EditField_Cell            matlab.ui.control.NumericEditField
        CellLabel                 matlab.ui.control.Label
        Switch_Box                matlab.ui.control.Switch
        SwitchBoxLabel            matlab.ui.control.Label
        EditField_Similarity      matlab.ui.control.NumericEditField
        SimilarityLabel           matlab.ui.control.Label
        CellSizeLabel             matlab.ui.control.Label
        EditField_CellSize_Max    matlab.ui.control.NumericEditField
        EditField_CellSize_Min    matlab.ui.control.NumericEditField
        Button_AlwayThres         matlab.ui.control.StateButton
        Button_AutoThres          matlab.ui.control.Button
        EditField_Thres_Max       matlab.ui.control.NumericEditField
        EditField_Thres_Min       matlab.ui.control.NumericEditField
        DisplaythresholdLabel     matlab.ui.control.Label
        ProcessPanel              matlab.ui.container.Panel
        Button_Last               matlab.ui.control.Button
        Button_First              matlab.ui.control.Button
        Button_Next               matlab.ui.control.Button
        Button_Previous           matlab.ui.control.Button
        Button_Pause              matlab.ui.control.Button
        Button_Play               matlab.ui.control.Button
        Selected_Timepoint        matlab.ui.control.NumericEditField
        CurrentEditFieldLabel     matlab.ui.control.Label
        MainPanel                 matlab.ui.container.Panel
        Button_RUN                matlab.ui.control.Button
        Button_SAVE               matlab.ui.control.Button
        Button_LOAD               matlab.ui.control.Button
        Button_RESET              matlab.ui.control.Button
        commu                     matlab.ui.control.EditField
        TrackingPanel             matlab.ui.container.Panel
        EditField_Sister          matlab.ui.control.EditField
        SisterEditFieldLabel      matlab.ui.control.Label
        EditField_Coords          matlab.ui.control.EditField
        CoordsEditFieldLabel      matlab.ui.control.Label
        EditField_CellNo          matlab.ui.control.NumericEditField
        CellNoEditFieldLabel      matlab.ui.control.Label
        Button_RestoreIND         matlab.ui.control.Button
        Button_RestoreAll         matlab.ui.control.Button
        Button_ReserveAll         matlab.ui.control.Button
        ListBox_Restcells         matlab.ui.control.ListBox
        Button_Terminate          matlab.ui.control.Button
        Button_DEL                matlab.ui.control.Button
        Button_GenSis             matlab.ui.control.Button
        Button_OPT                matlab.ui.control.Button
        ListBox_Cells             matlab.ui.control.ListBox
        Button_MANUAL             matlab.ui.control.Button
        Button_AUTO               matlab.ui.control.Button
        UIAxes_2                  matlab.ui.control.UIAxes
        FileProcessingPanel       matlab.ui.container.Panel
        ListBox_Filetype          matlab.ui.control.ListBox
        FileTypeLabel             matlab.ui.control.Label
        Selected_Timepoint_Last   matlab.ui.control.NumericEditField
        LastTPLabel               matlab.ui.control.Label
        Selected_Timepoint_First  matlab.ui.control.NumericEditField
        FirstTPLabel              matlab.ui.control.Label
        EditField_filepath        matlab.ui.control.EditField
        Selected_Channel          matlab.ui.control.NumericEditField
        ChannelEditFieldLabel     matlab.ui.control.Label
        Selected_Plane            matlab.ui.control.NumericEditField
        PlaneEditFieldLabel       matlab.ui.control.Label
        Selected_Field            matlab.ui.control.NumericEditField
        FieldEditFieldLabel       matlab.ui.control.Label
        Selected_Column           matlab.ui.control.NumericEditField
        ColumnEditFieldLabel      matlab.ui.control.Label
        Selected_Row              matlab.ui.control.NumericEditField
        RowEditFieldLabel         matlab.ui.control.Label
        Button_filepath           matlab.ui.control.Button
        Lamp                      matlab.ui.control.Lamp
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (SetObservable)
        img % Description
    end
    
    properties (Access = private)
        el % Listener of AlwayThres
        cellno % For store cell number
        cellpath % For store (x,y) position
        sisterlist % For store sister list
        sisterid % For check is sister
        DialogApp % For Dialog pop-up app
        stop % For interupt play image
        ROI % Add point to UIAxes by id
        rest_sisterlist % For store sister list permanently
        rest_sisterid % For store check is sister permanently
        rest_cellpath % For store (x,y) position permanently
        rest_cellno % For store cell number permanently
        RECT % Add box to UIAxes
        cellreg = 1; % Select segmented methods
        FFTiv = [0
            0
            2.13239470057269e-06
            2.04707496578750e-06
            2.18907656421901e-06
            2.53197485367440e-06
            2.25920187499180e-06
            2.22305545634141e-06
            2.40133420246436e-06
            2.40815825039931e-06
            2.55225172592046e-06
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            0.00121780324737345
            0.000374741035856574] % FFTiv
        FFTrv = [0
            0
            2.15511851365421e-06
            2.11029176708919e-06
            2.24253730293387e-06
            2.18632500355068e-06
            2.22305464499193e-06
            2.15843864441042e-06
            2.27528142433884e-06
            2.29409078981098e-06
            2.39013602425006e-06
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            0.000866781279847182
            0.000179508812481942] % FFTrv
        IFFTiv = [0
            0
            2.24159458072094e-06
            2.19994468529886e-06
            2.40967245449506e-06
            2.69104554594587e-06
            2.51398154450701e-06
            2.51946285052027e-06
            2.68912593334456e-06
            2.81528200600978e-06
            2.89605936493468e-06
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            0.00127984718242598
            0.000439821693907875] % IFFTiv
        
    end


    methods (Access = private)
        function [coords] = autofindpoint(app,M,sizeThres)
            % To find object in the current frame automatically
            % [coords] = autofindpoint(M,sizeThres)
            % M = Image
            % sizeThres is a vector contains [ minDiameter maxDiameter ]
            
            minNucDiameter=sizeThres(1);
            maxNucDiameter=sizeThres(2);
            minFormfactor=0.7;
            
            %H = fspecial('unsharp');
            %M = imfilter(M,H,'replicate');
            
            im=im2double(M);
            
            maxNucArea=round(pi*maxNucDiameter^2/4);
            
            combined_bw = zeros(size(M));
            testSet = linspace(1.4,8,12);
            
            for s = 1:length(testSet)
                
                [im2,thresh]  = edge(im,'canny',0,testSet(s));
                im3 = ~im2;
                bw=im3-bwareaopen(im3,maxNucArea,4);
                bw=imfill(bw,'holes');
                CC = bwconncomp(bw, 8);
                S = regionprops(CC,'EquivDiameter','Area','Perimeter');
                nucArea=cat(1,S.Area);
                nucPerim=cat(1,S.Perimeter);
                nucEquiDiameter=cat(1,S.EquivDiameter);
                nucFormfactor=4*pi*nucArea./(nucPerim.^2);
                L = labelmatrix(CC);
                BW2 = ismember(L, find(nucEquiDiameter >= minNucDiameter & ...
                    nucEquiDiameter < maxNucDiameter & ...
                    nucFormfactor>minFormfactor));
                
                %if sum(BW2(:)) > 0
                combined_bw = combined_bw | BW2;
                %end
            end
            
            S = regionprops(combined_bw,'Centroid','Perimeter','Area');
            coords = [];
            cInd = 1;
            imageP20  = prctile(M(:),20);
            
            for i=1:length(S)
                temCoord = S(i).Centroid;
                if M(round(temCoord(2)),round(temCoord(1))) >= imageP20 %&& 4*pi*S(i).Area/S(i).Perimeter^2 > minFormfactor
                    coords(cInd,:) = round(temCoord);
                    cInd= cInd+1;
                end
            end
        end
        
        function coordtopoint(app,tp,id,coord,labellogic)
            % To add point of coordinate to the image
            % p = coordtopoint(app,tp,id,coord,color,labellogic)
            % tp is timepoint
            % id is no. of object
            % coord is a vector contains [ x y ]
            % color can be 'r' or [ 1 0 0 ]
            % axis (in this case is use handles.axes1)
            % labellogic is a mode for draw if 0=auto , 1=manual
            
            if labellogic == 1 % auto point
                
                check = app.sisterid(id,:); % check if mother , sister ?
                
                if check(1) == 0 % Non-dividing object
                    color = [99 255 92]./255;
                    
                    app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Position', coord, ...
                    'Color', color, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on', ...
                    'MarkerSize', 4);
                    
                elseif check(1) == 1 && tp >= check(2) % Dividing object
                    color = [253 101 108]./255;
                    
                    app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Position', coord, ...
                    'Color', color, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on', ...
                    'MarkerSize', 4);
                
                elseif check(1) == 1 && tp <check(2) % Dividing object before tp
                    return;
                end
                
            elseif labellogic == 2 % manual point
                app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Color', [99 255 92]./255, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on', ...
                    'MarkerSize', 4);
                
            elseif labellogic == 3 % manual sister point
                app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Color', [253 101 108]./255, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on', ...
                    'MarkerSize', 4);
                
            end
            
            %'SelectedColor', [179 118 255]./255, ...
            
            % labellogic is a label visibility if 0=hide , 1=show
            
            %if labellogic
            %    labelvis = 'on';
            %else
            %    labelvis = 'off';
            %end
            
            
            %addlistener(app.ROI{id},'MovingROI',@allevents);
            addlistener(app.ROI{id},'ROIMoved',@allevents);
            
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    %case{'MovingROI'}
                    %prev = evt.PreviousPosition;
                    %curr = round(evt.CurrentPosition);
                    case{'ROIMoved'}
                        %delete(app.ROI{id});
                        app.ROI{id} = src;
                        prev = evt.PreviousPosition;
                        curr = round(evt.CurrentPosition);
                        app.cellpath{tp}(id,:) = curr;
                        sis = app.sisterlist{tp}(id,:);
                        %app.ListBox_Cells.Items{id} = num2str([ id curr sis ]);
                        
                        % Update sister
                        if app.sisterid(id,1) == 0  % check if mother ?
                            id4 = sis(1);
                            id5 = sis(2);
                            id6 = sis(3);
                            if id4 ~= -1 && tp < app.sisterid(id4,2)
                                app.cellpath{tp}(id4,:) = curr;
                            end
                            if id5 ~= -1 && tp < app.sisterid(id5,2)
                                app.cellpath{tp}(id5,:) = curr;
                            end
                            if id6 ~= -1 && tp < app.sisterid(id6,2)
                                app.cellpath{tp}(id6,:) = curr;
                            end
                        end
                        
                        for i = 1:length(app.cellno{tp})
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                        
                end
                app.ListBox_Cells.Value = id;
                ListBox_CellsValueChanged(app, event);
            end
        end
        
        function [x,y,BW] = centroidedge(app,M,xg,yg)
            % templateToCentroid Method Edging
            
            BWc = zeros(size(M));
            for i=1.2:0.6:3
                edgedIm = edge(M,'canny',0,i);
                
                BW = imfill(edgedIm,'holes');
                
                BW = bwmorph(BW,'open',1);
                BW = bwselect(BW,xg,yg);
                
                BWc = BWc | BW;
                
            end
            
            if ~isempty(xg) && ~isempty(yg)
                BW = bwselect(BWc,xg,yg);
            else
                BW = BWc;
            end
            
            S  = regionprops(BW, 'centroid');
            
            if ~isfield(S,'Centroid')
                x = xg;
                y = yg;
                
            elseif length(S)==1
                x = round(S.Centroid(1));
                y = round(S.Centroid(2));
            elseif length(S)>1
                for i=1:length(S)
                    cx(i) = S{i}.Centroid(1);
                    cy(i) = S{i}.Centroid(2);
                    cdistance(i) = pdist([cx(i) cy(i);round(size(M,2)/2) round(size(M,1)/2)]);
                end
                x=round(cx(cdistance == min(cdistance)));
                y=round(cy(cdistance == min(cdistance)));
            else
                x=xg;
                y=yg;
            end
        end
        
        function [x,y,BW] = centroidthresh(app,M,xg,yg)
            % templateToCentroid Method Thresholding
            
            BW = im2bw(imadjust(M), 1.3*graythresh(imadjust(M)));
            if ~isempty(xg) && ~isempty(yg)
                BW = bwselect(BW,xg,yg);
            else
                BW = BW;
            end
            
            S  = regionprops(BW, 'centroid');
            
            if ~isfield(S,'Centroid')
                x = xg;
                y = yg;
                
            elseif length(S)==1
                x = round(S.Centroid(1));
                y = round(S.Centroid(2));
            elseif length(S)>1
                for i=1:length(S)
                    cx(i) = S{i}.Centroid(1);
                    cy(i) = S{i}.Centroid(2);
                    cdistance(i) = pdist([cx(i) cy(i);round(size(M,2)/2) round(size(M,1)/2)]);
                end
                x=round(cx(cdistance == min(cdistance)));
                y=round(cy(cdistance == min(cdistance)));
            else
                x=xg;
                y=yg;
            end
            
        end
        
        function [x,y,BW] = centroidcontour(app,M,xg,yg,outerbox)
            % tempM = imadjust(M);
            M(1,:) = 0;
            M(end,:) = 0;
            M(:,1) = 0;
            M(:,end) = 0;
            outBW = modchenvese(M,150,0.07,30);
            se = strel('disk',1);
            outBW = imclose(outBW,se);
            outBW(1,:) = 0;
            outBW(end,:) = 0;
            outBW(:,1) = 0;
            outBW(:,end) = 0;
            
            if ~isempty(xg) && ~isempty(yg)
                BW = bwselect(outBW,xg,yg);
            else
                BW = outBW;
            end
            
            
            S  = regionprops(BW, 'centroid');
            
            if ~isfield(S,'Centroid')
                x = xg;
                y = yg;
                
            elseif length(S)==1
                x = round(S.Centroid(1));
                y = round(S.Centroid(2));
            elseif length(S)>1
                for i=1:length(S)
                    cx(i) = S{i}.Centroid(1);
                    cy(i) = S{i}.Centroid(2);
                    cdistance(i) = pdist([cx(i) cy(i);round(size(M,2)/2) round(size(M,1)/2)]);
                end
                x=round(cx(cdistance == min(cdistance)));
                y=round(cy(cdistance == min(cdistance)));
            else
                x=xg;
                y=yg;
            end
            
            %   Adapted from code by Yue Wu (yue.wu@tufts.edu)
            %   http://www.mathworks.com/matlabcentral/fileexchange/23445
            function seg = modchenvese(I,num_iter,mu,outerbox)
                I = imadjust(I);
                s = outerbox./min(size(I,1),size(I,2)); % resize scale
                
                n = zeros(size(I));
                midY = round(size(n,1)/2);
                midX = round(size(n,2)/2);
                boxsize = round(size(I,1)*0.07);%round(outerbox/2*.9);
                n(midY-boxsize:midY+boxsize,midX-boxsize:midX+boxsize) = 1;
                I = imresize(I,s);
                mask = imresize(n,s);
                
                phi0 = bwdist(mask)-bwdist(1-mask)+im2double(mask)-.5;
                force = eps;
                
                %-- Display settings
                %figure();
                %subplot(2,2,1); imshow(I); title('Input Image');axis equal;
                %subplot(2,2,2); contour(flipud(phi0), [0 0], 'r','LineWidth',1); title('initial contour');axis equal;
                %subplot(2,2,3); title('Segmentation');axis equal;
                %-- End Display original image and mask
                
                %-- Main loop
                for n=1:num_iter
                    inidx = find(phi0>=0); % frontground index
                    outidx = find(phi0<0); % background index
                    force_image = 0; % initial image force for each layer
                    L = im2double(I); % get one image component
                    
                    c1 = sum(sum(L.*Heaviside(phi0)))/(length(inidx)+eps); % average inside of Phi0
                    c2 = sum(sum(L.*(1-Heaviside(phi0))))/(length(outidx)+eps); % verage outside of Phi0
                    force_image=-(L-c1).^2+(L-c2).^2+force_image;
                    % sum Image Force on all components (used for vector image)
                    % if 'chan' is applied, this loop become one sigle code as a
                    % result of layer = 1
                    
                    
                    % calculate the external force of the image
                    force = mu*kappa(phi0)./max(max(abs(kappa(phi0))))+force_image;
                    
                    % normalized the force
                    force = force./(max(max(abs(force))));
                    
                    % get stepsize dt
                    dt=0.5;
                    
                    % get parameters for checking whether to stop
                    old = phi0;
                    phi0 = phi0+dt.*force;
                    new = phi0;
                    indicator = checkstop(old,new,dt);


                    % intermediate output
                    %if(mod(n,20) == 0)
                    %    showphi(I,phi0,n);
                    %end;
                    if indicator % decide to stop or continue
                        %showphi(I,phi0,n);
                        
                        %make mask from SDF
                        seg = phi0<=0; %-- Get mask from levelset
                        
                        midY = round(size(seg,1)/2);
                        midX = round(size(seg,2)/2);
                        if seg(midY,midX) > 0
                            seg = bwselect(seg,midX,midY);
                        else
                            seg = ~seg;
                            seg = bwselect(seg,midX,midY);
                            %seg = bwselect(~seg,midX,midY);
                        end
                        
                        
                        %subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
                        seg = imresize(seg,1/s);
                        return;
                    end
                end
                %showphi(I,phi0,n);
                
                %make mask from SDF
                seg = phi0<=0; %-- Get mask from levelset
                
                midY = round(size(seg,1)/2);
                midX = round(size(seg,2)/2);
                
                if seg(midY,midX) > 0
                    seg = bwselect(seg,midX,midY);
                else
                    seg = ~seg;
                    seg = bwselect(seg,midX,midY);
                end
                
                %subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
                seg = imresize(seg,1/s);
                
            end
            
            function showphi(I, phi, i)
                % show curve evolution of phi
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                for j = 1:size(phi,3)
                    phi_{j} = phi(:,:,j); %#ok<*AGROW>
                end
                imshow(I,'initialmagnification','fit','displayrange',[0 255]);
                hold on;
                
                if size(phi,3) == 1
                    contour(phi_{1}, [0 0], 'r','LineWidth',4);
                    contour(phi_{1}, [0 0], 'g','LineWidth',1.3);
                else
                    contour(phi_{1}, [0 0], 'r','LineWidth',4);
                    contour(phi_{1}, [0 0], 'x','LineWidth',1.3);
                    contour(phi_{2}, [0 0], 'g','LineWidth',4);
                    contour(phi_{2}, [0 0], 'x','LineWidth',1.3);
                end
                hold off;
                title([num2str(i) ' Iterations']);
                drawnow;
            end
            
            function KG = kappa(I)
                % get curvature information of input image
                % input: 2D image I
                % output: curvature matrix KG
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                I = double(I);
                [m,n] = size(I);
                P = padarray(I,[1,1],1,'pre');
                P = padarray(P,[1,1],1,'post');
                
                % central difference
                fy = P(3:end,2:n+1)-P(1:m,2:n+1);
                fx = P(2:m+1,3:end)-P(2:m+1,1:n);
                fyy = P(3:end,2:n+1)+P(1:m,2:n+1)-2*I;
                fxx = P(2:m+1,3:end)+P(2:m+1,1:n)-2*I;
                fxy = 0.25.*(P(3:end,3:end)-P(1:m,3:end)+P(3:end,1:n)-P(1:m,1:n));
                G = (fx.^2+fy.^2).^(0.5);
                K = (fxx.*fy.^2-2*fxy.*fx.*fy+fyy.*fx.^2)./((fx.^2+fy.^2+eps).^(1.5));
                KG = K.*G;
                KG(1,:) = eps;
                KG(end,:) = eps;
                KG(:,1) = eps;
                KG(:,end) = eps;
                KG = KG./max(max(abs(KG)));
            end
            
            function indicator = checkstop(old,new,dt)
                % indicate whether we should performance further iteraions or stop
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                layer = size(new,3);
                
                for i = 1:layer
                    old_{i} = old(:,:,i);
                    new_{i} = new(:,:,i);
                end
                
                if layer
                    ind = find(abs(new)<=.5);
                    M = length(ind);
                    Q = sum(abs(new(ind)-old(ind)))./M;
                    if Q<=dt*.18^2 %#ok<*BDSCI>
                        indicator = 1;
                    else
                        indicator = 0;
                    end
                else
                    ind1 = find(abs(old_{1})<1);
                    ind2 = find(abs(old_{2})<1);
                    M1 = length(ind1);
                    M2 = length(ind2);
                    Q1 = sum(abs(new_{1}(ind1)-old_{1}(ind1)))./M1;
                    Q2 = sum(abs(new_{2}(ind2)-old_{2}(ind2)))./M2;
                    if Q1<=dt*.18^2 && Q2<=dt*.18^2
                        indicator = 1;
                    else
                        indicator = 0;
                    end
                end
                return
            end
            
            function H=Heaviside(z)
                % Heaviside step function (smoothed version)
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                Epsilon=10^(-5);
                H=zeros(size(z,1),size(z,2));
                idx1=find(z>Epsilon);
                idx2=find(z<Epsilon & z>-Epsilon);
                H(idx1)=1; %#ok<*FNDSB>
                for i=1:length(idx2)
                    H(idx2(i))=1/2*(1+z(idx2(i))/Epsilon+1/pi*sin(pi*z(idx2(i))/Epsilon));
                end
            end
            
        end
        
        function [out]=detbestlength2(app,FFTrv,FFTiv,IFFTiv,size1,size2,isreal1,isreal2)
            % [out]=detbestlength2(FFTrv,FFTiv,IFFTiv,size1,size2,isreal1,isreal2)
            % Determine the best parameters for Overlap-Add FFT-based convolution.
            %
            % INPUT
            % FFTrv:   vector with costs of FFT for real 1d vectors
            % FFTiv:   vector with costs of FFT for complex 1d vectors
            % IFFTiv:  vector with costs of IFFT for complex 1d vectors
            % size1:   size(first_image)
            % size2:   size(second_image)
            % isreal1: 1 if first image is real, 0 otherwise (complex)
            % isreal2: 1 if second image is real, 0 otherwise (complex)
            % OUTPUT
            % out:    the optimized parameters:
            %         out.inverse:     if 1 the two input have to be inverted
            %         out.fftxfirst:   if one the image has to be fft first along
            %                          x-dimension
            %         out.ifftxfirst:  if one the product of spectra has to be ifft
            %                          first along x-dimensio
            %         out.nfftx:       the best length for fft transform along
            %                          x-dimension
            %         out.nffty:       the best length for fft transform along
            %                          y-dimension
            %         out.filterxfirst if 1 the filter has to be fft fisrt alng
            %                          x-dimension
            %
            out           = [];
            % the 3 input vectors have to be the same length
            L             = length(FFTrv);
            % a default value (just as Inf)
            infinitevalue = 99*10^99;
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %----------------------------------------------------- a image and b filter
            if isreal1 && isreal2
                ax = size1(1);
                ay = size1(2);
                bx = size2(1);
                by = size2(2);
                val0 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                if cv1<val0
                                    val0 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val0
                                    val0 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val0
                                    val0 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val0
                                    val0 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val0
                                    val0 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val0
                                    val0 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val0
                                    val0 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val0
                                    val0 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %----------------------------------------------------- a filter and b image
                ax = size2(1);
                ay = size2(2);
                bx = size1(1);
                by = size1(2);
                val1 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                if cv1<val1
                                    val1 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val1
                                    val1 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val1
                                    val1 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val1
                                    val1 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val1
                                    val1 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val1
                                    val1 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val1
                                    val1 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val1
                                    val1 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %--------------------------------------------------------------------------
                if val1<val0
                    out.inverse = 1;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                else
                    out.inverse = 0;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                end
                out.nfftx = x;
                out.nffty = y;
                if t==1
                    out.filterxfirst = 0;
                else
                    out.filterxfirst = 1;
                end
                return;
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %----------------------------------------------------- a image and b filter
            if ~isreal1 && ~isreal2
                ax = size1(1);
                ay = size1(2);
                bx = size2(1);
                by = size2(2);
                val0 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                if cv1<val0
                                    val0 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val0
                                    val0 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val0
                                    val0 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val0
                                    val0 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val0
                                    val0 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val0
                                    val0 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val0
                                    val0 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val0
                                    val0 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %----------------------------------------------------- a filter and b image
                ax = size2(1);
                ay = size2(2);
                bx = size1(1);
                by = size1(2);
                val1 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTiv(ii)~=0 && FFTiv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                if cv1<val1
                                    val1 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val1
                                    val1 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val1
                                    val1 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val1
                                    val1 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val1
                                    val1 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val1
                                    val1 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val1
                                    val1 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val1
                                    val1 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %--------------------------------------------------------------------------
                if val1<val0
                    out.inverse = 1;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                else
                    out.inverse = 0;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                end
                out.nfftx = x;
                out.nffty = y;
                if t==1
                    out.filterxfirst = 0;
                else
                    out.filterxfirst = 1;
                end
                return;
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %----------------------------------------------------- a image and b filter
            if isreal1 && ~isreal2
                ax = size1(1);
                ay = size1(2);
                bx = size2(1);
                by = size2(2);
                val0 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                if cv1<val0
                                    val0 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val0
                                    val0 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val0
                                    val0 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val0
                                    val0 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val0
                                    val0 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val0
                                    val0 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val0
                                    val0 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val0
                                    val0 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %----------------------------------------------------- a filter and b image
                ax = size2(1);
                ay = size2(2);
                bx = size1(1);
                by = size1(2);
                val1 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                if cv1<val1
                                    val1 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val1
                                    val1 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val1
                                    val1 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val1
                                    val1 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val1
                                    val1 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val1
                                    val1 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val1
                                    val1 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val1
                                    val1 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %--------------------------------------------------------------------------
                if val1<val0
                    out.inverse = 1;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                else
                    out.inverse = 0;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                end
                out.nfftx = x;
                out.nffty = y;
                if t==1
                    out.filterxfirst = 0;
                else
                    out.filterxfirst = 1;
                end
                return;
            end
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++++
            %----------------------------------------------------- a image and b filter
            if ~isreal1 && isreal2
                ax = size1(1);
                ay = size1(2);
                bx = size2(1);
                by = size2(2);
                val0 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTrv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTiv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTiv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTrv(ii) + ii*FFTiv(jj));
                                if cv1<val0
                                    val0 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val0
                                    val0 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val0
                                    val0 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val0
                                    val0 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val0
                                    val0 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val0
                                    val0 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val0
                                    val0 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val0
                                    val0 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %----------------------------------------------------- a filter and b image
                ax = size2(1);
                ay = size2(2);
                bx = size1(1);
                by = size1(2);
                val1 = infinitevalue;
                for ii=1:L
                    for jj=1:L
                        if FFTrv(ii)~=0 && FFTrv(jj)~=0
                            Lx    = ii-bx+1;
                            Ly    = jj-by+1;
                            if Lx>0 && Ly>0
                                nx    = ceil(ax/Lx);
                                ny    = ceil(ay/Ly);
                                cv1 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv2 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv3 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv4 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (ii*FFTiv(jj) + jj*FFTiv(ii));
                                cv5 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv6 = nx*ny*(ii*FFTrv(jj) + jj*FFTiv(ii) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv7 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + jj*IFFTiv(ii) + ii*IFFTiv(jj)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                cv8 = nx*ny*(jj*FFTrv(ii) + ii*FFTiv(jj) + ii*IFFTiv(jj) + jj*IFFTiv(ii)) + (jj*FFTiv(ii) + ii*FFTiv(jj));
                                if cv1<val1
                                    val1 = cv1;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 1;
                                end
                                if cv2<val1
                                    val1 = cv2;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 1;
                                end
                                if cv3<val1
                                    val1 = cv3;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 1;
                                end
                                if cv4<val1
                                    val1 = cv4;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 1;
                                end
                                if cv5<val1
                                    val1 = cv5;
                                    x    = ii;
                                    y    = jj;
                                    z    = 1;
                                    t    = 0;
                                end
                                if cv6<val1
                                    val1 = cv6;
                                    x    = ii;
                                    y    = jj;
                                    z    = 2;
                                    t    = 0;
                                end
                                if cv7<val1
                                    val1 = cv7;
                                    x    = ii;
                                    y    = jj;
                                    z    = 3;
                                    t    = 0;
                                end
                                if cv8<val1
                                    val1 = cv8;
                                    x    = ii;
                                    y    = jj;
                                    z    = 4;
                                    t    = 0;
                                end
                            end
                        end
                    end
                end
                %--------------------------------------------------------------------------
                if val1<val0
                    out.inverse = 1;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                else
                    out.inverse = 0;
                    if z==1 || z==2
                        out.fftxfirst = 0;
                    else
                        out.fftxfirst = 1;
                    end
                    if z==1 || z==3
                        out.ifftxfirst = 1;
                    else
                        out.ifftxfirst = 0;
                    end
                end
                out.nfftx = x;
                out.nffty = y;
                if t==1
                    out.filterxfirst = 0;
                else
                    out.filterxfirst = 1;
                end
                return;
            end
        end
        
        function [x, y, maxVal] = corrMatching2(app,frameImg, templateImg, opt)
            % -------------------------------------------------------------------------
            % Function corrMatching: Template Matching using Correlation Coefficients
            % Inputs:
            %           frameImg = gray or color frame image
            %           templateImg = gray or color template image
            %           threshC = threshold of rejecting detected region (default = .75)
            %                     e.g. if the detected region has a corrCoef>threshC
            %                     then the algorithm accepts it as a detection,
            %                     otherwise rejects it as a false alarm.
            % Output:
            %           corrScore = 2D matrix of correlation coefficients
            %           boundingBox = [upperLeftPixel.y upperLeftPixel.x height width]
            %
            % -------------------------------------------------------------------------
            % By Yue Wu (Rex)
            % Department of Electrical and Computer Engineering
            % Tufts University
            % Medford, MA
            % 08/30/2010
            % -------------------------------------------------------------------------
            
            %% 1. initialization
            
            if size(frameImg,3) ~=1
                frameGray = rgb2gray(frameImg);
            else
                frameGray = frameImg;
            end
            frameGray = double(frameGray);
            
            if size(templateImg,3) ~=1
                templateGray = rgb2gray(templateImg);
            else
                templateGray = templateImg;
            end
            
            templateGray = double(templateGray);
            [templateHeight,templateWidth] = size(templateGray);
            
            
            %% 2. correlation calculation
            
            frameMean = fftolamopt2(app,frameGray,ones(size(templateGray))./numel(templateGray),opt,'full');
            templateMean = mean(templateGray(:));
            
            corrPartI = fftolamopt2(app,frameGray,fliplr(flipud(templateGray-templateMean)),opt,'full')./numel(templateGray);
            corrPartII = frameMean.*sum(templateGray(:)-templateMean);
            stdFrame = sqrt(fftolamopt2(app,frameGray.^2,ones(size(templateGray))./numel(templateGray),opt,'full')-frameMean.^2);
            stdTemplate = std(templateGray(:));
            corrScore = (corrPartI-corrPartII)./(stdFrame.*stdTemplate);
            %figure(3);imshow(corrScore); drawnow;
            %% 3. finding most likely region
            
            [maxVal,maxIdx] = max(corrScore(:));
            [y, x] = ind2sub([size(corrScore,1),size(corrScore,2)],maxIdx);
            %figure(3); hold on; plot(x,y,'rx');
            x = x-round(templateWidth/2);
            y = y-round(templateHeight/2);
        end
        
        function [out]=fftolamopt2(app,a,b,opt,shape)
            % [out]=fftolamopt2(a,b,siz1,siz2,shape)
            %
            % Overlap-add method FFT-based 2D convolution
            % Example:
            %   load fftexecutiontimes;                                                        % load FFTrv, FFTiv and IFFTiv in workspace
            %   a   = rand(500,500);                                                           % first image
            %   b   = rand(340,220);                                                           % second image
            %   opt = detbestlength2(FFTrv,FFTiv,IFFTiv,size(a),size(b),isreal(a),isreal(b));  % optimized parameters
            %   y0  = fftolamopt2(a,b,opt);                                                    % equivalent to y0 = conv2(a,b);
            %
            % INPUT
            % a:     first image (2D double matrix)
            % b:     second image (2D double matrix)
            % opt:   the optimized parameters calculated by detbestlength.m function
            %        opt = detbestlength(FFTrv,FFTiv,IFFTiv,size(a),size(b));
            % shape: returns a subsection of the 2D convolution with size specified by
            %        'shape':
            %          'full'  - (default) returns the full 2-D convolution,
            %          'same'  - returns the central part of the convolution
            %                    that is the same size as A.
            %          'valid' - returns only those parts of the convolution
            %                    that are computed without the zero-padded
            %                    edges. size(C) = [ma-mb+1,na-nb+1] when
            %                    all(size(A) >= size(B)), otherwise C is empty.
            % See also conv2.
            % OUTPUT
            % out:   2D convolution of a and b matrices: out = conv2(a,b);
            
            
            % Original size
            [z1x,z1y] = size(a);
            [z2x,z2y] = size(b);
            
            % Reverse a and b if necessary
            if opt.inverse
                atemp = a;
                a     = b;
                b     = atemp;
            end
            
            fftorder  = zeros(2,1);
            ifftorder = zeros(2,1);
            fftsize   = zeros(2,1);
            filterord = zeros(2,1);
            filtersiz = zeros(2,1);
            
            if (opt.fftxfirst == 1)
                fftorder(1)  = 1;
                fftorder(2)  = 2;
                fftsize(1)   = opt.nfftx;
                fftsize(2)   = opt.nffty;
            else
                fftorder(1)  = 2;
                fftorder(2)  = 1;
                fftsize(1)   = opt.nffty;
                fftsize(2)   = opt.nfftx;
            end
            
            
            if (opt.ifftxfirst == 1)
                ifftorder(1) = 1;
                ifftorder(2) = 2;
            else
                ifftorder(1) = 2;
                ifftorder(2) = 1;
            end
            
            if opt.filterxfirst==1
                filterord(1) = 1;
                filterord(2) = 2;
                
                filtersiz(1) = opt.nfftx;
                filtersiz(2) = opt.nffty;
            else
                filterord(1) = 2;
                filterord(2) = 1;
                
                filtersiz(1) = opt.nffty;
                filtersiz(2) = opt.nfftx;
            end
            
            siz1          = opt.nfftx;
            siz2          = opt.nffty;
            
            [ax,ay]       = size(a);
            [bx,by]       = size(b);
            dimx          = ax+bx-1;
            dimy          = ay+by-1;
            nfftx         = siz1;
            nffty         = siz2;
            Lx            = nfftx-bx+1;
            Ly            = nffty-by+1;
            B             = fft(fft(b,filtersiz(1),filterord(1)),filtersiz(2),filterord(2));
            out           = zeros(dimx,dimy);
            x0 = 1;
            while x0 <= ax
                x1   = min(x0+Lx-1,ax);
                y0   = 1;
                endx = min(dimx,x0+nfftx-1);
                while y0 <= ay
                    y1                   = min(y0+Ly-1,ay);
                    endy                 = min(dimy,y0+nffty-1);
                    X                    = fft(fft(a(x0:x1,y0:y1),fftsize(1),fftorder(1)),fftsize(2),fftorder(2));
                    Y                    = ifft(ifft(X.*B,[],ifftorder(1)),[],ifftorder(2));
                    out(x0:endx,y0:endy) = out(x0:endx,y0:endy)+Y(1:(endx-x0+1),1:(endy-y0+1));
                    y0                   = y0+Ly;
                end
                x0 = x0+Lx;
            end
            if isreal(a) && isreal(b)
                out=real(out);
            end
            if nargin<4 || strcmp(shape,'full')
                return;
            end
            if strcmp(shape,'valid')
                if ((z1x<z2x)||(z1y<z2y))
                    out = [];
                else
                    px  = z2x;
                    py  = z2y;
                    out = out(px:px+z1x-z2x,py:py+z1y-z2y);
                end
                return;
            end
            if strcmp(shape,'same')
                px  = ((z2x-1)+mod((z2x-1),2))/2;
                py  = ((z2y-1)+mod((z2y-1),2))/2;
                out = out(px+1:px+z1x,py+1:py+z1y);
                return;
            end
        end
        
        function outputim = loadimage(app,fileformat,imlocation,tp,SourceF)
            % This function is loading image from Columbus extension
            % outputim = loadimage(fileformat,imlocation,tp,SourceF)
            % fileformat is '%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif'
            % imlocation is a vector contains : [ Row Column Field Plane Channel ]
            % tp         is a selected timepoint
            % SourceF    is a path that stores the image
            
            row = imlocation(1);
            col = imlocation(2);
            field = imlocation(3);
            plane = imlocation(4);
            channel = imlocation(5);
            
            outputim = [];
            
            filename = sprintf(fileformat,row,col,field,tp,plane,channel);
            
            if exist(fullfile(SourceF,filename),'file')
                outputim = imread(fullfile(SourceF,filename));
            else
                app.commu.Value = sprintf('"%s" file not found!',filename);
                app.Lamp.Color = 'r';
                return;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            warning('off','all');
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            
            title(app.UIAxes_2, []);
            xlabel(app.UIAxes_2, []);
            ylabel(app.UIAxes_2, []);
            app.UIAxes_2.XAxis.TickLabels = {};
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % old UIFigure size (X,Y,W,H) : 100,100,1200,760
            
            % old UIAxes size (X,Y,W,H) : 331,43,859,678
            width = 859+(position(3)-1200);
            height = 678+(position(4)-760);
            app.UIAxes.Position = [ app.UIAxes.Position(1) app.UIAxes.Position(2) width height];
            
            % old commu size (X,Y,W,H) : 331,729,859,22
            width = 859+(position(3)-1200);
            Y = 729+(position(4)-760);
            app.commu.Position = [ app.commu.Position(1) Y width app.commu.Position(4)];
            
            % old Slider_Frame size (X,Y,W,H) : 388,26,802,3
            width = 802+(position(3)-1200);
            app.Slider_Frame.Position = [ app.Slider_Frame.Position(1) app.Slider_Frame.Position(2) width app.Slider_Frame.Position(4)];
            
            % old Text01 size (X,Y,W,H) : 13,721,308,30
            Y = 721+(position(4)-760);
            app.Text01.Position = [ app.Text01.Position(1) Y app.Text01.Position(3) app.Text01.Position(4)];
            
            % old Text02 size (X,Y,W,H) : 13,693,308,22
            Y = 693+(position(4)-760);
            app.Text02.Position = [ app.Text02.Position(1) Y  app.Text02.Position(3) app.Text02.Position(4)];
            
            % old FileProcessingPanel size (X,Y,W,H) : 13,529,200,160
            Y = 529*(position(4)/760);
            app.FileProcessingPanel.Position = [ app.FileProcessingPanel.Position(1) Y  app.FileProcessingPanel.Position(3) app.FileProcessingPanel.Position(4)];
            
            % old TrackingPanel size (X,Y,W,H) : 13,64,308,309
            Y = 64*(position(4)/760);
            app.TrackingPanel.Position = [ app.TrackingPanel.Position(1) Y  app.TrackingPanel.Position(3) app.TrackingPanel.Position(4)];
            
            % old MainPanel size (X,Y,W,H) : 13,10,308,50
            Y = 10*(position(4)/760);
            app.MainPanel.Position = [ app.MainPanel.Position(1) Y  app.MainPanel.Position(3) app.MainPanel.Position(4)];
            
            % old ProcessPanel size (X,Y,W,H) : 221,519,100,170
            Y = 519*(position(4)/760);
            app.ProcessPanel.Position = [ app.ProcessPanel.Position(1) Y  app.ProcessPanel.Position(3) app.ProcessPanel.Position(4)];
            
            % old AdjustPanel size (X,Y,W,H) : 13,379,200,144
            Y = 379*(position(4)/760);
            app.AdjustPanel.Position = [ app.AdjustPanel.Position(1) Y  app.AdjustPanel.Position(3) app.AdjustPanel.Position(4)];
            
            % old TemplateMatchingPanel size (X,Y,W,H) : 221,450,100,60
            Y = 450*(position(4)/760);
            app.TemplateMatchingPanel.Position = [ app.TemplateMatchingPanel.Position(1) Y  app.TemplateMatchingPanel.Position(3) app.TemplateMatchingPanel.Position(4)];
            
            % old WholeImduplicatePanel size (X,Y,W,H) : 221,381,100,60
            Y = 381*(position(4)/760);
            app.WholeImduplicatePanel.Position = [ app.WholeImduplicatePanel.Position(1) Y  app.WholeImduplicatePanel.Position(3) app.WholeImduplicatePanel.Position(4)];
            
        end

        % Button pushed function: Button_RESET
        function Button_RESETPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            app.ListBox_Cells.Items = {''};
            app.stop = 0;
            app.ROI = [];
            app.sisterlist = [];
            app.sisterid = [];
            app.cellpath = [];
            app.cellno = [];
            app.rest_sisterlist = [];
            app.rest_sisterid = [];
            app.rest_cellpath = [];
            app.rest_cellno = [];
            app.EditField_CellNo.Value = 1;
            app.EditField_Coords.Value = '(x,y)';
            app.EditField_Sister.Value = '-1 -1 -1';
            app.EditField_Thres_Min.Value = 0;
            app.EditField_Thres_Max.Value = 1;
            
            app.Selected_Timepoint.Value = app.Selected_Timepoint_First.Value;
            app.Slider_Frame.Limits = [app.Selected_Timepoint_First.Value,app.Selected_Timepoint_Last.Value];
            
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            
            % Show initial image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.commu.Value = 'Click AUTO for smart initialization. Click MANUAL for manual cell choosing (also possible after AUTO).';
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_SAVE
        function Button_SAVEPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            if ~isempty(app.img)
                
                fileformat = app.ListBox_Filetype.Value;
                SourceF = app.EditField_filepath.Value;
                Row = app.Selected_Row.Value;
                Column = app.Selected_Column.Value;
                Field = app.Selected_Field.Value;
                Plane = app.Selected_Plane.Value;
                Channel = app.Selected_Channel.Value;
                
                first_tp = app.Selected_Timepoint_First.Value;
                last_tp = app.Selected_Timepoint_Last.Value;
                c_tp = app.Selected_Timepoint.Value;
                
                cellpath_mat = -1*(ones(size(app.cellpath{c_tp},1),2,length(app.cellpath)));
                sisterList_mat = -1*(ones(size(app.sisterlist{c_tp},1),size(app.sisterlist{c_tp},2),length(app.sisterlist)));
                %sisterID_mat = 0*(ones(size(app.sisterid{c_tp},1),length(app.sisterid)));
                
                sisterID_mat = app.sisterid;
                
                for tp=1:length(app.cellpath)
                    if ~isempty(app.cellpath{tp})
                        cellpath_mat(:,:,tp) = app.cellpath{tp};
                        sisterList_mat(:,:,tp) = app.sisterlist{tp};
                        %sisterID_mat(:,tp) = app.sisterID{tp};
                    end
                end
                
                H5filename = ['H5OUT_r' num2str(Row) '_c' num2str(Column) '.h5'];
                cellpath_name = ['/field' num2str(Field) '/cellpath'];
                sisterList_name = ['/field' num2str(Field) '/sisterList'];
                sisterID_name = ['/field' num2str(Field) '/sisterID'];
                
                if exist(fullfile(SourceF,H5filename),'file')
                    fileattrib(fullfile(SourceF,H5filename),'+w');
                    
                    fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
                    if ~H5L.exists(fid,cellpath_name,'H5P_DEFAULT')
                        H5F.close(fid);
                        app.commu.Value = ['Initializing ' H5filename ':' cellpath_name]; pause(0.0001);
                    else
                        H5L.delete(fid,cellpath_name,'H5P_DEFAULT');
                        app.commu.Value = ['Overwriting ' H5filename ':' cellpath_name]; pause(0.0001);
                        H5F.close(fid);
                    end
                else
                    app.commu.Value = ['Initializing ' H5filename ':' cellpath_name]; pause(0.0001);
                end
                
                h5create(fullfile(SourceF,H5filename), cellpath_name, [size(cellpath_mat,1), size(cellpath_mat,2), size(cellpath_mat,3)], 'Datatype', 'double', 'ChunkSize', [1, size(cellpath_mat,2), size(cellpath_mat,3)], 'Deflate', 9);
                h5write(fullfile(SourceF,H5filename), cellpath_name, cellpath_mat, [1 1 1], [size(cellpath_mat,1) size(cellpath_mat,2) size(cellpath_mat,3)]);
                
                fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
                if ~H5L.exists(fid,sisterList_name,'H5P_DEFAULT')
                    H5F.close(fid);
                    app.commu.Value = ['Initializing ' H5filename ':' sisterList_name]; pause(0.0001);
                else
                    H5L.delete(fid,sisterList_name,'H5P_DEFAULT');
                    app.commu.Value = ['Overwriting ' H5filename ':' sisterList_name]; pause(0.0001);
                    H5F.close(fid);
                end
                
                h5create(fullfile(SourceF,H5filename), sisterList_name, [size(sisterList_mat,1), size(sisterList_mat,2), size(sisterList_mat,3)], 'Datatype', 'double', 'ChunkSize', [1, size(sisterList_mat,2), size(sisterList_mat,3)], 'Deflate', 9);
                h5write(fullfile(SourceF,H5filename), sisterList_name, sisterList_mat, [1 1 1], [size(sisterList_mat,1) size(sisterList_mat,2) size(sisterList_mat,3)]);
                
                fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
                if ~H5L.exists(fid,sisterID_name,'H5P_DEFAULT')
                    H5F.close(fid);
                    app.commu.Value = ['Initializing ' H5filename ':' sisterID_name]; pause(0.0001);
                else
                    H5L.delete(fid,sisterID_name,'H5P_DEFAULT');
                    app.commu.Value = ['Overwriting ' H5filename ':' sisterID_name]; pause(0.0001);
                    H5F.close(fid);
                end
                
                h5create(fullfile(SourceF,H5filename), sisterID_name, [size(sisterID_mat,1), size(sisterID_mat,2)], 'Datatype', 'double', 'ChunkSize', [1, size(sisterID_mat,2)], 'Deflate', 9);
                h5write(fullfile(SourceF,H5filename), sisterID_name, sisterID_mat, [1 1], [size(sisterID_mat,1) size(sisterID_mat,2)]);
                
                app.commu.Value = ['Your data is saved to ' H5filename]; pause(0.0001);
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            
        end

        % Button pushed function: Button_LOAD
        function Button_LOADPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            
            first_tp = app.Selected_Timepoint_First.Value;
            last_tp = app.Selected_Timepoint_Last.Value;
            
            H5filename = ['H5OUT_r' num2str(Row) '_c' num2str(Column) '.h5'];
            cellpath_name = ['/field' num2str(Field) '/cellpath'];
            sisterList_name = ['/field' num2str(Field) '/sisterList'];
            sisterID_name = ['/field' num2str(Field) '/sisterID'];
            
            if ~exist(fullfile(SourceF,H5filename),'file')
                app.commu.Value = [H5filename '.mat does not exist.']; pause(0.0001);
                return
            else
                fileattrib(fullfile(SourceF,H5filename),'+w');
            end
            
            % Load Original seed points
            
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if H5L.exists(fid,cellpath_name,'H5P_DEFAULT')
                H5F.close(fid);
                cellpathinfo = h5info(fullfile(SourceF,H5filename), cellpath_name);
                
                cellpath_mat = h5read(fullfile(SourceF,H5filename),cellpath_name,[1 1 1], [cellpathinfo.Dataspace.Size(1) cellpathinfo.Dataspace.Size(2) cellpathinfo.Dataspace.Size(3)]);
                
                for tp=first_tp:cellpathinfo.Dataspace.Size(3)
                    app.cellpath{tp} = cellpath_mat(:,:,tp);
                    app.cellno{tp} = (1:size(app.cellpath{tp},1))';
                end
            end
            
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if H5L.exists(fid,sisterList_name,'H5P_DEFAULT')
                H5F.close(fid);
                sisterListinfo = h5info(fullfile(SourceF,H5filename), sisterList_name);
                sisterList_mat = h5read(fullfile(SourceF,H5filename),sisterList_name,[1 1 1], [sisterListinfo.Dataspace.Size(1) sisterListinfo.Dataspace.Size(2) sisterListinfo.Dataspace.Size(3)]);
                
                for tp=first_tp:sisterListinfo.Dataspace.Size(3)
                    app.sisterlist{tp} = sisterList_mat(:,:,tp);
                end
            else
                for tp=first_tp:cellpathinfo.Dataspace.Size(3)
                    app.sisterlist{tp} = -1*ones(size(cellpath_mat,1),3);
                end
            end
            
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if H5L.exists(fid,sisterID_name,'H5P_DEFAULT')
                H5F.close(fid);
                sisterIDinfo = h5info(fullfile(SourceF,H5filename), sisterID_name);
                sisterID_mat = h5read(fullfile(SourceF,H5filename),sisterID_name,[1 1], [sisterIDinfo.Dataspace.Size(1) sisterIDinfo.Dataspace.Size(2)]);
                
                app.sisterid = sisterID_mat;
                
                %for tp=first_tp:sisterIDinfo.Dataspace.Size(2)
                %    app.sisterid{tp} = sisterID_mat(:,tp);
                %end
            else
                app.sisterid = 0*ones(size(cellpath_mat,1),2);
                
                %for tp=first_tp:cellpathinfo.Dataspace.Size(2)
                %    app.sisterid{tp} = 0*ones(size(cellpath_mat,1),2);
                %end
            end
            
            
            tp = app.Selected_Timepoint.Value;
            
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.commu.Value = 'Loading Completed.';
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_filepath
        function Button_filepathPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            selpath = uigetdir(pwd,'Select Image Folder');
            app.EditField_filepath.Value = selpath;
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_AutoThres
        function Button_AutoThresPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            if ~isempty(app.img)
                thres = stretchlim(app.img);
                app.EditField_Thres_Min.Value = thres(1);
                app.EditField_Thres_Max.Value = thres(2);
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end

                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: Button_AlwayThres
        function Button_AlwayThresValueChanged(app, event)
            value = app.Button_AlwayThres.Value;
            if value == 1
                app.Button_AlwayThres.BackgroundColor = [0.00,1.00,0.00];
                %Button_AutoThresPushed(app, event);
                app.el = addlistener(app,'img','PostSet',@allevents);
            else
                app.Button_AlwayThres.BackgroundColor = [0.94,0.94,0.94];
                delete(app.el);
                
            end
            
            function allevents(src,evt)
                thres = stretchlim(app.img);
                app.EditField_Thres_Min.Value = thres(1);
                app.EditField_Thres_Max.Value = thres(2);
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    tp = app.Selected_Timepoint.Value;
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            app.stop = 0;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            
            for tp = app.Selected_Timepoint.Value:app.Selected_Timepoint_Last.Value
                app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
                if ~isempty(app.img)
                    imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    if ~isempty(app.cellno)
                        if tp > numel(app.cellno)
                            app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                        else
                            n = length(app.cellno{tp});
                            for i = 1:n
                                posi = app.cellpath{tp}(i,:);
                                sis = app.sisterlist{tp}(i,:);
                                coordtopoint(app,tp,i,posi,1);
                                app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                            end
                        end
                    end
                    
                end
                app.Selected_Timepoint.Value = tp;
                app.Slider_Frame.Value = tp;
                app.Lamp.Color = 'g';
                pause(1);
                if app.stop
                    app.stop = 0; %rest the button state
                    app.commu.Value = 'Pause!'; pause(0.5); app.commu.Value = '';
                    app.Lamp.Color = [0.9 0.9 0.9];
                    break;
                end
            end
            app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_Pause
        function Button_PausePushed(app, event)
            app.commu.Value = ''; pause(0.0001);
            app.stop = 1; %trigger the pause button state
        end

        % Button pushed function: Button_Previous
        function Button_PreviousPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            if tp > app.Selected_Timepoint_First.Value && tp <= app.Selected_Timepoint_Last.Value
                tp = tp - 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_Next
        function Button_NextPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            if tp >= app.Selected_Timepoint_First.Value && tp < app.Selected_Timepoint_Last.Value
                tp = tp + 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');

                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end

                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_First
        function Button_FirstPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_First.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end

                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_Last
        function Button_LastPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_Last.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Value changed function: EditField_Thres_Min
        function EditField_Thres_MinValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.EditField_Thres_Min.Value;
            tp = app.Selected_Timepoint.Value;
            if ~isempty(app.img)
                imshow(imadjust((app.img),[value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: EditField_Thres_Max
        function EditField_Thres_MaxValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.EditField_Thres_Max.Value;
            tp = app.Selected_Timepoint.Value;
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: Selected_Timepoint
        function Selected_TimepointValueChanged(app, event)
            value = app.Selected_Timepoint.Value;
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Timepoint.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
            app.Slider_Frame.Value = tp;
        end

        % Value changed function: Selected_Row
        function Selected_RowValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Row.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Value changed function: Selected_Column
        function Selected_ColumnValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Column.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Value changed function: Selected_Field
        function Selected_FieldValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Field.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Value changed function: Selected_Plane
        function Selected_PlaneValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Plane.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Value changed function: Selected_Channel
        function Selected_ChannelValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Channel.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
        end

        % Value changed function: Selected_Timepoint_First
        function Selected_Timepoint_FirstValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Timepoint_First.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Limits = [value,app.Selected_Timepoint_Last.Value];
            app.Slider_Frame.Value = tp;
        end

        % Value changed function: Selected_Timepoint_Last
        function Selected_Timepoint_LastValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Selected_Timepoint_Last.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Limits = [app.Selected_Timepoint_First.Value,value];
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_AUTO
        function Button_AUTOPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            if ~isempty(app.img)
                
                % Add point
                cellareathres = [ app.EditField_CellSize_Min.Value app.EditField_CellSize_Max.Value ];
                [coords_full] = autofindpoint(app,app.img,cellareathres);
                coords_max = sortrows([(1:size(coords_full,1))' (coords_full(:,1)-size(app.img,2)/2).^2+(coords_full(:,2)-size(app.img,1)/2).^2],2);
                coords  = coords_full(coords_max(1:size(coords_max,1)),:);
                clear coords_full coords_max;
                
                tp = app.Selected_Timepoint.Value;
                
                try oldpsize = length(app.cellno{tp}); catch; oldpsize = length(app.cellno); end
                for cell=1:size(coords,1)
                    id = cell+oldpsize;
                    app.sisterid(id,:) = [ 0 0 ];
                    for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                        app.cellno{t}(id,1) = id;
                        app.sisterlist{t}(id,:) = [ -1 -1 -1 ];
                        if t == tp
                            app.cellpath{t}(id,:) = round(coords(cell,:));
                        else
                            app.cellpath{t}(id,:) = [-1 -1];
                        end
                    end
                end
                app.commu.Value = 'Completed generating point.';
                
                % Show image with coordinate
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
                
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_MANUAL
        function Button_MANUALPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            if ~isempty(app.img)
                app.commu.Value = 'Manually, Left-click to pick the points';
                
                tp = app.Selected_Timepoint.Value;
                
                try n = length(app.cellno{tp}); catch; n = length(app.cellno); end
                
                % Add point
                id = n+1;
                coordtopoint(app,tp,id,[],2);
                PosPOC = round(get(app.ROI{id},'Position'));
                drawnow; % Force figure update
                
                app.sisterid(id,:) = [ 0 0 ];
                
                for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                    app.cellno{t}(id,1) = id;
                    app.sisterlist{t}(id,:) = [ -1 -1 -1 ];
                    if t == tp
                        app.cellpath{t}(id,:) = PosPOC;
                    else
                        app.cellpath{t}(id,:) = [-1 -1];
                    end
                end
                
                posi = app.cellpath{tp}(id,:);
                sis = app.sisterlist{tp}(id,:);
                app.ListBox_Cells.Items{id} = num2str([ id posi sis ]);
                app.commu.Value = '';
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_OPT
        function Button_OPTPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            if ~isempty(app.img)
                str = app.ListBox_Cells.Value;
                if isempty(str)
                    app.commu.Value = 'Please select one of cells in ListBox.';
                    app.Lamp.Color = 'r';
                    return;
                end
                myid = regexp(str,'-?\d+','match');
                id = str2double(myid{1});
                
                tp = app.Selected_Timepoint.Value;
                
                cellsize = app.EditField_Cell.Value;
                
                xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                xR=min(app.cellpath{tp}(id,1)+cellsize,size(app.img,2));
                yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                yR=min(app.cellpath{tp}(id,2)+cellsize,size(app.img,1));
                template = app.img(yL:yR,xL:xR);
                xg = round(size(template,2)/2);
                yg = round(size(template,1)/2);
                
                switch app.cellreg
                    case 1
                        [x1,y1,BW] = centroidedge(app,template,xg,yg);
                    case 2
                        [x1,y1,BW] = centroidthresh(app,template,xg,yg);
                    case 3
                        [x1,y1,BW] = centroidcontour(app,template,xg,yg,app.EditField_Outer.Value);
                        
                end
                
                app.cellpath{tp}(id,:) = [xL+x1 yL+y1];
                
                % Update sister
                if app.sisterid(id,1) == 0 % check if mother ?
                    id4 = str2double(myid{4});
                    id5 = str2double(myid{5});
                    id6 = str2double(myid{6});
                    if id4 ~= -1 && tp < app.sisterid(id4,2)
                        app.cellpath{tp}(id4,:) = [xL+x1 yL+y1];
                    end
                    if id5 ~= -1 && tp < app.sisterid(id5,2)
                        app.cellpath{tp}(id5,:) = [xL+x1 yL+y1];
                    end
                    if id6 ~= -1 && tp < app.sisterid(id6,2)
                        app.cellpath{tp}(id6,:) = [xL+x1 yL+y1];
                    end
                end
                
                % Update the picture
                edge = bwmorph(BW,'remove');
                
                % Show image UIAxes with coordinate
                cla(app.UIAxes);
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                for i = 1:length(app.cellno{tp})
                    posi = app.cellpath{tp}(i,:);
                    sis = app.sisterlist{tp}(i,:);
                    coordtopoint(app,tp,i,posi,1);
                    app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                end
                
                % Show image UIAxes_2
                cla(app.UIAxes_2);
                imshow(imadjust((template),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_2);
                axis(app.UIAxes_2,'image');
                hold(app.UIAxes_2,'on');
                
                [y,x]=find(edge);
                plot(app.UIAxes_2,x,y,'.r','MarkerSize',1,'Linewidth',2);
                plot(app.UIAxes_2,app.cellpath{tp}(id,1)-xL,app.cellpath{tp}(id,2)-yL,'xr','LineWidth',2);
                drawnow;
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_GenSis
        function Button_GenSisPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            if ~isempty(app.img)
                
                str = app.ListBox_Cells.Value;
                if isempty(str)
                    app.commu.Value = 'Please select one of cells in ListBox.';
                    app.Lamp.Color = 'r';
                    return;
                end
                myid = regexp(str,'-?\d+','match');
                id0 = str2double(myid{1});
                
                app.commu.Value = 'Manually, Left-click to pick the points';
                
                tp = app.Selected_Timepoint.Value;
                
                try n = length(app.cellno{tp}); catch; n = length(app.cellno); end
                
                % Add point
                id = n+1;
                coordtopoint(app,tp,id,[],3);
                PosPOC = round(get(app.ROI{id},'Position'));
                drawnow; % Force figure update
                
                app.sisterid(id,:) = [ 1 tp ];
                
                for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                    check = 0;
                    app.cellno{t}(id,1) = id;
                    app.sisterlist{t}(id,:) = [ id0 -1 -1 ];
                    
                    for i = 1:3
                        if app.sisterlist{t}(id0,i) == -1
                            app.sisterlist{t}(id0,i) = id;
                            check = 1;
                            break;
                        end
                    end
                    
                    if check == 0; break; end
                    
                    if t == tp
                        app.cellpath{t}(id,:) = PosPOC;
                    elseif t > tp
                        app.cellpath{t}(id,:) = [-1 -1];
                    elseif t < tp
                        app.cellpath{t}(id,:) = app.cellpath{t}(id0,:);
                    end
                end
                
                if check == 0
                    app.commu.Value = 'Error! Cannot Select Sister more than 3 passages.';
                    app.Lamp.Color = 'r';
                    return;
                end
                
                % Update Mother
                posi = app.cellpath{tp}(id0,:);
                sis = app.sisterlist{tp}(id0,:);
                app.ListBox_Cells.Items{id0} = num2str([ id0 posi sis ]);
                
                % Update Sister
                posi = app.cellpath{tp}(id,:);
                sis = app.sisterlist{tp}(id,:);
                app.ListBox_Cells.Items{id} = num2str([ id posi sis ]);
                app.commu.Value = '';
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_DEL
        function Button_DELPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            str = app.ListBox_Cells.Value;
            if isempty(str)
                app.commu.Value = 'Please select one of cells in ListBox.';
                app.Lamp.Color = 'r';
                return;
            end
            myid = regexp(str,'-?\d+','match');
            id = str2double(myid{1});
            
            n = length(app.ListBox_Cells.Items);
            tp = app.Selected_Timepoint.Value;
            
            for i = 1:n
                delete(app.ROI{i}); % delete the ROI
            end
            app.ROI = [];
            
            app.sisterid(id,:) = [];
            
            id4 = str2double(myid{4});
            id5 = str2double(myid{5});
            id6 = str2double(myid{6});
            
            for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                % Update mother
                if id4 ~= -1
                    check = app.sisterlist{t}(id4,:);
                    check(check == id) = -1;
                    app.sisterlist{t}(id4,:) = check;
                end
                if id5 ~= -1
                    check = app.sisterlist{t}(id5,:);
                    check(check == id) = -1;
                    app.sisterlist{t}(id5,:) = check;
                end
                if id6 ~= -1
                    check = app.sisterlist{t}(id6,:);
                    check(check == id) = -1;
                    app.sisterlist{t}(id6,:) = check;
                end
                
                % Remove sister
                app.cellno{t} = (1:n-1)';
                app.cellpath{t}(id,:) = [];
                app.sisterlist{t}(id,:) = [];
            end
            
            app.ListBox_Cells.Items = {''};
            
            if ~isempty(app.cellno)
                if tp > numel(app.cellno)
                    app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                else
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                end
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_Terminate
        function Button_TerminatePushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            str = app.ListBox_Cells.Value;
            if isempty(str)
                app.commu.Value = 'Please select one of cells in ListBox.';
                app.Lamp.Color = 'r';
                return;
            end
            myid = regexp(str,'-?\d+','match');
            id = str2double(myid{1});
            
            n = length(app.ListBox_Cells.Items);
            tp = app.Selected_Timepoint.Value;
            
            for i = 1:n
                delete(app.ROI{i}); % delete the ROI
            end
            app.ROI = [];
            
            for t = tp:app.Selected_Timepoint_Last.Value
                app.cellpath{t}(id,:) = [-2 -2];
                app.sisterlist{t}(id,:) = [-1 -1 -1];
            end
            
            if ~isempty(app.cellno)
                if tp > numel(app.cellno)
                    app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                else
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                end
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_ReserveAll
        function Button_ReserveAllPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            try n0 = length(app.cellno{tp}); catch; n0 = length(app.cellno); end
            if n0 == 0
                app.commu.Value = 'Error! Object not found in working store.';
                app.Lamp.Color = 'r';
                return;
            end
            
            try n = length(app.rest_cellno{tp}); catch; n = length(app.rest_cellno); end
            
            for i = 1:n0
                id = i+n;
                app.rest_sisterid(id,:) = app.sisterid(i,:);
                
                for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                    app.rest_cellno{t}(id,1) = id;
                    app.rest_cellpath{t}(id,:) = app.cellpath{t}(i,:);
                    app.rest_sisterlist{t}(id,:) = app.sisterlist{t}(i,:);
                end
            end
            
            for i = 1:length(app.rest_cellno{tp})
                posi = app.rest_cellpath{tp}(i,:);
                sis = app.rest_sisterlist{tp}(i,:);
                app.ListBox_Restcells.Items{i} = num2str([ i posi sis ]);
            end
            
            app.ListBox_Cells.Items = {''};
            app.stop = 0;
            app.ROI = [];
            app.sisterlist = [];
            app.sisterid = [];
            app.cellpath = [];
            app.cellno = [];
            
            if ~isempty(app.img)
                % Show image
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_RestoreAll
        function Button_RestoreAllPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            try n0 = length(app.rest_cellno{tp}); catch; n0 = length(app.rest_cellno); end
            if n0 == 0
                app.commu.Value = 'Error! Object not found in resting store.';
                app.Lamp.Color = 'r';
                return;
            end
            
            try n = length(app.cellno{tp}); catch; n = length(app.cellno); end
            
            for i = 1:n0
                id = i+n;
                app.sisterid(id,:) = app.rest_sisterid(i,:);
                
                for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                    app.cellno{t}(id,1) = id;
                    app.cellpath{t}(id,:) = app.rest_cellpath{t}(i,:);
                    app.sisterlist{t}(id,:) = app.rest_sisterlist{t}(i,:);
                end
            end
            
            app.ListBox_Restcells.Items = {''};
            app.stop = 0;
            app.rest_sisterlist = [];
            app.rest_sisterid = [];
            app.rest_cellpath = [];
            app.rest_cellno = [];
            
            if ~isempty(app.img)
                % Show image
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                
                for i = 1:length(app.cellno{tp})
                    posi = app.cellpath{tp}(i,:);
                    sis = app.sisterlist{tp}(i,:);
                    coordtopoint(app,tp,i,posi,1);
                    app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                end
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_RestoreIND
        function Button_RestoreINDPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            str = app.ListBox_Restcells.Value;
            if isempty(str)
                app.commu.Value = 'Please select one of cells in Resting ListBox.';
                app.Lamp.Color = 'r';
                return;
            end
            myid = regexp(str,'-?\d+','match');
            id0 = str2double(myid{1});
            
            n0 = length(app.ListBox_Restcells.Items);
            tp = app.Selected_Timepoint.Value;
            
            try n = length(app.cellno{tp}); catch; n = length(app.cellno); end
            
            id = n+1;
            
            app.sisterid(id,:) = app.rest_sisterid(id0,:);
            app.rest_sisterid(id0,:) = [];
            
            for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                app.cellno{t} = (1:n+1)';
                app.cellpath{t}(id,:) = app.rest_cellpath{t}(id0,:);
                app.sisterlist{t}(id,:) = app.rest_sisterlist{t}(id0,:);
                
                app.rest_cellno{t} = (1:n0-1)';
                app.rest_cellpath{t}(id0,:) = [];
                app.rest_sisterlist{t}(id0,:) = [];
                
                %# Update daughter cellid 
                sl = app.rest_sisterlist{t};
                sl(sl>id0) = sl(sl>id0) - 1;
                app.rest_sisterlist{t} = sl;
            end
            app.ListBox_Restcells.Items = {''};
            
            for i = 1:length(app.rest_cellno{tp})
                posi = app.rest_cellpath{tp}(i,:);
                sis = app.rest_sisterlist{tp}(i,:);
                app.ListBox_Restcells.Items{i} = num2str([ i posi sis ]);
            end
            
            if ~isempty(app.img)
                % Show image
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                for i = 1:length(app.cellno{tp})
                    posi = app.cellpath{tp}(i,:);
                    sis = app.sisterlist{tp}(i,:);
                    coordtopoint(app,tp,i,posi,1);
                    app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                end
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: Switch_Box
        function Switch_BoxValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Switch_Box.Value;
            
            if strcmp(value,'On')
                
                str = app.ListBox_Cells.Value;
                if isempty(str)
                    app.commu.Value = 'Please select one of cells in ListBox.';
                    app.Lamp.Color = 'r';
                    return;
                end
                myid = regexp(str,'-?\d+','match');
                id = str2double(myid{1});
                
                tp = app.Selected_Timepoint.Value;
                
                cellsize = app.EditField_Cell.Value;
                outersize = app.EditField_Outer.Value;
                
                app.RECT{1} = drawrectangle(app.UIAxes, 'Position', [app.cellpath{tp}(id,1)-cellsize,app.cellpath{tp}(id,2)-cellsize,2*cellsize-1,2*cellsize-1], ...
                    'LineWidth', 2, 'Color', [0 73 218]./255, 'FaceAlpha',0, ...
                    'InteractionsAllowed', 'none');
                
                app.RECT{2} = drawrectangle(app.UIAxes, 'Position', [app.cellpath{tp}(id,1)-outersize app.cellpath{tp}(id,2)-outersize 2*outersize-1 2*outersize-1], ...
                    'LineWidth', 2, 'Color', [4 41 169]./255, 'FaceAlpha',0, ...
                    'InteractionsAllowed', 'none');
                
            elseif strcmp(value,'Off')
                if ~isempty(app.RECT)
                    delete(app.RECT{1});
                    delete(app.RECT{2});
                end
                app.RECT = [];
                
            end
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: ListBox_Cells
        function ListBox_CellsValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.ListBox_Cells.Value;
            
            myid = regexp(value,'-?\d+','match');
            id = str2double(myid{1});
            
            tp = app.Selected_Timepoint.Value;
            
            if app.cellpath{tp}(id,1) > -1 && app.cellpath{tp}(id,2) > -1
                
                app.EditField_CellNo.Value = id;
                app.EditField_Coords.Value = sprintf('( %d , %d )',app.cellpath{tp}(id,1),app.cellpath{tp}(id,2));
                app.EditField_Sister.Value = num2str(app.sisterlist{tp}(id,:));
                
                cellsize = app.EditField_Cell.Value;
                
                xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                xR=min(app.cellpath{tp}(id,1)+cellsize,size(app.img,2));
                yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                yR=min(app.cellpath{tp}(id,2)+cellsize,size(app.img,1));
                template = app.img(yL:yR,xL:xR);
                
                xg = round(size(template,2)/2);
                yg = round(size(template,1)/2);
                
                switch app.cellreg
                    case 1
                        [~,~,BW] = centroidedge(app,template,xg,yg);
                    case 2
                        [~,~,BW] = centroidthresh(app,template,xg,yg);
                    case 3
                        [~,~,BW] = centroidcontour(app,template,xg,yg,app.EditField_Outer.Value);
                        
                end
                
                edge = bwmorph(BW,'remove');
                
                
                % Show image
                imshow(imadjust((template),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_2);
                axis(app.UIAxes_2,'image');
                hold(app.UIAxes_2,'on');
                
                [y,x]=find(edge);
                plot(app.UIAxes_2,x,y,'.r','MarkerSize',1,'Linewidth',2);
                plot(app.UIAxes_2,app.cellpath{tp}(id,1)-xL,app.cellpath{tp}(id,2)-yL,'xr','LineWidth',2);
                drawnow;
            else
                app.EditField_CellNo.Value = id;
                app.EditField_Coords.Value = '(x,y)';
                app.EditField_Sister.Value = '-1 -1 -1';
                
                app.commu.Value = ['Cell#' num2str(id) ' does not exist at current time point.'];
                app.Lamp.Color = 'r'; return;
            end
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_TM_Reverse
        function Button_TM_ReversePushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            if ~isempty(app.img)
                
                fileformat = app.ListBox_Filetype.Value;
                SourceF = app.EditField_filepath.Value;
                Row = app.Selected_Row.Value;
                Column = app.Selected_Column.Value;
                Field = app.Selected_Field.Value;
                Plane = app.Selected_Plane.Value;
                Channel = app.Selected_Channel.Value;
                imlocation = [ Row Column Field Plane Channel ];
                
                cFrame = app.Selected_Timepoint.Value;
                endFrame   = app.Selected_Timepoint_Last.Value;
                
                cellsize = app.EditField_Cell.Value;
                outersize = app.EditField_Outer.Value;
                
                increment = -1; % Forward = 1 , Backward = -1
                
                if cFrame ~= endFrame
                    
                    tp = cFrame;
                    
                    previousframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    previousframe_small = previousframe(300:end-300,300:end-300);
                    
                    tp = cFrame + increment;
                    
                    currentframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    opt1 = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,size(currentframe),size(previousframe),isreal(currentframe),isreal(previousframe));
                    
                    app.Selected_Timepoint.Value = tp;
                    app.Slider_Frame.Value = tp;
                    
                    app.img = currentframe;
                    imshow(imadjust((currentframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    [xg,yg,maxVal] = corrMatching2(app,currentframe,previousframe_small,opt1);
                    
                    if maxVal > app.EditField_Similarity.Value
                        xshift = xg-round(size(currentframe,2)/2);
                        yshift = yg-round(size(currentframe,1)/2);
                    else
                        xshift = 0;
                        yshift = 0;
                    end
                    
                    opt = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,2*[outersize outersize],2*[cellsize cellsize],1,1);
                    
                    if  length(app.sisterlist) >= tp && ~isempty(app.sisterlist{tp}) && ~isempty(app.sisterlist{1})
                        sisExistInd = find(app.sisterlist{tp}(:,1) ~= -1 & app.sisterlist{tp}(:,1) ~= -2);
                    else
                        sisExistInd = [];
                    end
                    
                    PosInd = find(app.cellpath{tp-increment}(:,1)>0 & app.cellpath{tp-increment}(:,2)>0)';
                    
                    for cell=PosInd
                        if isempty(find(cell==sisExistInd,1))
                            app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        end
                        
                        xL=max(app.cellpath{tp-increment}(cell,1)-cellsize,1);
                        xR=min(app.cellpath{tp-increment}(cell,1)+cellsize,size(previousframe,2));
                        yL=max(app.cellpath{tp-increment}(cell,2)-cellsize,1);
                        yR=min(app.cellpath{tp-increment}(cell,2)+cellsize,size(previousframe,1));
                        
                        template = previousframe(yL:yR,xL:xR);
                        
                        xL=max(app.cellpath{tp-increment}(cell,1)+xshift-outersize,1);
                        xR=min(app.cellpath{tp-increment}(cell,1)+xshift+outersize-1,size(currentframe,2));
                        yL=max(app.cellpath{tp-increment}(cell,2)+yshift-outersize,1);
                        yR=min(app.cellpath{tp-increment}(cell,2)+yshift+outersize-1,size(currentframe,1));
                        
                        testframe = currentframe(yL:yR,xL:xR);
                        
                        [x1,y1,maxVal] = corrMatching2(app,testframe, template,opt);
                        if isempty(x1) || maxVal < app.EditField_Similarity.Value
                            x1 = app.cellpath{tp-increment}(cell,1)-xL;
                            y1 = app.cellpath{tp-increment}(cell,2)-yL;
                        end
                        
                        switch app.cellreg
                            case 1
                                [x11,y11,~] = centroidedge(app,testframe,x1,y1);
                            case 2
                                [x11,y11,~] = centroidthresh(app,testframe,x1,y1);
                            case 3
                                [x11,y11,~] = centroidcontour(app,testframe,x1,y1,app.EditField_Outer.Value);
                                
                        end
                        
                        if ~isempty(x11) || ~isempty(y11)
                            x1 = x11;
                            y1 = y11;
                        end
                        
                        app.cellpath{tp}(cell,:) = [xL+x1 yL+y1];
                        
                    end
                    
                    DeathInd = find(app.cellpath{tp-increment}(:,1)==-2)';
                    
                    for cell=DeathInd
                        app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        app.cellpath{tp}(cell,:) = app.cellpath{tp-increment}(cell,:);
                    end
                    
                    for i = 1:length(app.cellno{tp})
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                    
                    app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                else
                    app.Lamp.Color = 'r';
                    app.commu.Value = 'Warning! This is the end of the frame. If there is more time point, please initialize in first & last timepoint and click RESET button.';
                    return;
                end
            end
        end

        % Button pushed function: Button_TM_Forward
        function Button_TM_ForwardPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            if ~isempty(app.img)
                
                fileformat = app.ListBox_Filetype.Value;
                SourceF = app.EditField_filepath.Value;
                Row = app.Selected_Row.Value;
                Column = app.Selected_Column.Value;
                Field = app.Selected_Field.Value;
                Plane = app.Selected_Plane.Value;
                Channel = app.Selected_Channel.Value;
                imlocation = [ Row Column Field Plane Channel ];
                
                cFrame = app.Selected_Timepoint.Value;
                endFrame   = app.Selected_Timepoint_Last.Value;
                
                cellsize = app.EditField_Cell.Value;
                outersize = app.EditField_Outer.Value;
                
                increment = 1; % Forward = 1 , Backward = -1
                
                if cFrame ~= endFrame
                    
                    tp = cFrame;
                    
                    previousframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    previousframe_small = previousframe(300:end-300,300:end-300);
                    
                    tp = cFrame + increment;
                    
                    currentframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    opt1 = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,size(currentframe),size(previousframe),isreal(currentframe),isreal(previousframe));
                    
                    app.Selected_Timepoint.Value = tp;
                    app.Slider_Frame.Value = tp;
                    
                    app.img = currentframe;
                    imshow(imadjust((currentframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    [xg,yg,maxVal] = corrMatching2(app,currentframe,previousframe_small,opt1);
                    
                    if maxVal > app.EditField_Similarity.Value
                        xshift = xg-round(size(currentframe,2)/2);
                        yshift = yg-round(size(currentframe,1)/2);
                    else
                        xshift = 0;
                        yshift = 0;
                    end
                    
                    opt = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,2*[outersize outersize],2*[cellsize cellsize],1,1);
                    
                    if  length(app.sisterlist) >= tp && ~isempty(app.sisterlist{tp}) && ~isempty(app.sisterlist{1})
                        sisExistInd = find(app.sisterlist{tp}(:,1) ~= -1 & app.sisterlist{tp}(:,1) ~= -2);
                    else
                        sisExistInd = [];
                    end
                    
                    PosInd = find(app.cellpath{tp-increment}(:,1)>0 & app.cellpath{tp-increment}(:,2)>0)';
                    
                    for cell=PosInd
                        if isempty(find(cell==sisExistInd,1))
                            app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        end
                        
                        xL=max(app.cellpath{tp-increment}(cell,1)-cellsize,1);
                        xR=min(app.cellpath{tp-increment}(cell,1)+cellsize,size(previousframe,2));
                        yL=max(app.cellpath{tp-increment}(cell,2)-cellsize,1);
                        yR=min(app.cellpath{tp-increment}(cell,2)+cellsize,size(previousframe,1));
                        
                        template = previousframe(yL:yR,xL:xR);
                        
                        xL=max(app.cellpath{tp-increment}(cell,1)+xshift-outersize,1);
                        xR=min(app.cellpath{tp-increment}(cell,1)+xshift+outersize-1,size(currentframe,2));
                        yL=max(app.cellpath{tp-increment}(cell,2)+yshift-outersize,1);
                        yR=min(app.cellpath{tp-increment}(cell,2)+yshift+outersize-1,size(currentframe,1));
                        
                        testframe = currentframe(yL:yR,xL:xR);
                        
                        [x1,y1,maxVal] = corrMatching2(app,testframe, template,opt);
                        if isempty(x1) || maxVal < app.EditField_Similarity.Value
                            x1 = app.cellpath{tp-increment}(cell,1)-xL;
                            y1 = app.cellpath{tp-increment}(cell,2)-yL;
                        end
                        
                        switch app.cellreg
                            case 1
                                [x11,y11,~] = centroidedge(app,testframe,x1,y1);
                            case 2
                                [x11,y11,~] = centroidthresh(app,testframe,x1,y1);
                            case 3
                                [x11,y11,~] = centroidcontour(app,testframe,x1,y1,app.EditField_Outer.Value);
                                
                        end
                        
                        if ~isempty(x11) || ~isempty(y11)
                            x1 = x11;
                            y1 = y11;
                        end
                        
                        app.cellpath{tp}(cell,:) = [xL+x1 yL+y1];
                        
                    end
                    
                    DeathInd = find(app.cellpath{tp-increment}(:,1)==-2)';
                    
                    for cell=DeathInd
                        app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        app.cellpath{tp}(cell,:) = app.cellpath{tp-increment}(cell,:);
                    end
                    
                    for i = 1:length(app.cellno{tp})
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                    
                    app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                else
                    app.Lamp.Color = 'r';
                    app.commu.Value = 'Warning! This is the end of the frame. If there is more time point, please initialize in first & last timepoint and click RESET button.';
                    return;
                end
            end
        end

        % Button pushed function: Button_WID_Reverse
        function Button_WID_ReversePushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            if ~isempty(app.img)
                
                fileformat = app.ListBox_Filetype.Value;
                SourceF = app.EditField_filepath.Value;
                Row = app.Selected_Row.Value;
                Column = app.Selected_Column.Value;
                Field = app.Selected_Field.Value;
                Plane = app.Selected_Plane.Value;
                Channel = app.Selected_Channel.Value;
                imlocation = [ Row Column Field Plane Channel ];
                
                cFrame = app.Selected_Timepoint.Value;
                endFrame   = app.Selected_Timepoint_Last.Value;
                
                cellsize = app.EditField_Cell.Value;
                outersize = app.EditField_Outer.Value;
                
                increment = -1; % Forward = 1 , Backward = -1
                
                if cFrame ~= endFrame
                    
                    tp = cFrame;
                    
                    previousframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    previousframe = previousframe(100:end-100,100:end-100);
                    
                    tp = cFrame + increment;
                    
                    currentframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    opt = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,size(currentframe),size(previousframe),isreal(currentframe),isreal(previousframe));
                    
                    app.Selected_Timepoint.Value = tp;
                    app.Slider_Frame.Value = tp;
                    
                    app.img = currentframe;
                    imshow(imadjust((currentframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    [x,y,~] = corrMatching2(app,imresize(currentframe,1),imresize(previousframe,1),opt);
                    
                    xshift = x-round(size(currentframe,2)/2);
                    yshift = y-round(size(currentframe,1)/2);
                    
                    if  length(app.sisterlist) >= tp && ~isempty(app.sisterlist{tp}) && ~isempty(app.sisterlist{1})
                        sisExistInd = find(app.sisterlist{tp}(:,1) ~= -1 & app.sisterlist{tp}(:,1) ~= -2);
                    else
                        sisExistInd = [];
                    end
                    
                    PosInd = find(app.cellpath{tp-increment}(:,1)>0 & app.cellpath{tp-increment}(:,2)>0)';
                    
                    for cell=PosInd
                        if isempty(find(cell==sisExistInd,1))
                            app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        end
                        app.cellpath{tp}(cell,:) = [app.cellpath{tp-increment}(cell,1)+xshift app.cellpath{tp-increment}(cell,2)+yshift];
                    end
                    
                    DeathInd = find(app.cellpath{tp-increment}(:,1)==-2)';
                    
                    for cell=DeathInd
                        app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        app.cellpath{tp}(cell,:) = app.cellpath{tp-increment}(cell,:);
                    end
                    
                    for i = 1:length(app.cellno{tp})
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                    
                    app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                else
                    app.Lamp.Color = 'r';
                    app.commu.Value = 'Warning! This is the end of the frame. If there is more time point, please initialize in first & last timepoint and click RESET button.';
                    return;
                end
            end
        end

        % Button pushed function: Button_WID_Forward
        function Button_WID_ForwardPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            if ~isempty(app.img)
                
                fileformat = app.ListBox_Filetype.Value;
                SourceF = app.EditField_filepath.Value;
                Row = app.Selected_Row.Value;
                Column = app.Selected_Column.Value;
                Field = app.Selected_Field.Value;
                Plane = app.Selected_Plane.Value;
                Channel = app.Selected_Channel.Value;
                imlocation = [ Row Column Field Plane Channel ];
                
                cFrame = app.Selected_Timepoint.Value;
                endFrame   = app.Selected_Timepoint_Last.Value;
                
                cellsize = app.EditField_Cell.Value;
                outersize = app.EditField_Outer.Value;
                
                increment = 1; % Forward = 1 , Backward = -1
                
                if cFrame ~= endFrame
                    
                    tp = cFrame;
                    
                    previousframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    previousframe = previousframe(100:end-100,100:end-100);
                    
                    tp = cFrame + increment;
                    
                    currentframe = loadimage(app,fileformat,imlocation,tp,SourceF);
                    opt = detbestlength2(app,app.FFTrv,app.FFTiv,app.IFFTiv,size(currentframe),size(previousframe),isreal(currentframe),isreal(previousframe));
                    
                    app.Selected_Timepoint.Value = tp;
                    app.Slider_Frame.Value = tp;
                    
                    app.img = currentframe;
                    imshow(imadjust((currentframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    [x,y,~] = corrMatching2(app,imresize(currentframe,1),imresize(previousframe,1),opt);
                    
                    xshift = x-round(size(currentframe,2)/2);
                    yshift = y-round(size(currentframe,1)/2);
                    
                    if  length(app.sisterlist) >= tp && ~isempty(app.sisterlist{tp}) && ~isempty(app.sisterlist{1})
                        sisExistInd = find(app.sisterlist{tp}(:,1) ~= -1 & app.sisterlist{tp}(:,1) ~= -2);
                    else
                        sisExistInd = [];
                    end
                    
                    PosInd = find(app.cellpath{tp-increment}(:,1)>0 & app.cellpath{tp-increment}(:,2)>0)';
                    
                    for cell=PosInd
                        if isempty(find(cell==sisExistInd,1))
                            app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        end
                        app.cellpath{tp}(cell,:) = [app.cellpath{tp-increment}(cell,1)+xshift app.cellpath{tp-increment}(cell,2)+yshift];
                    end
                    
                    DeathInd = find(app.cellpath{tp-increment}(:,1)==-2)';
                    
                    for cell=DeathInd
                        app.sisterlist{tp}(cell,:) = app.sisterlist{tp-increment}(cell,:);
                        app.cellpath{tp}(cell,:) = app.cellpath{tp-increment}(cell,:);
                    end
                    
                    for i = 1:length(app.cellno{tp})
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                    
                    app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                else
                    app.Lamp.Color = 'r';
                    app.commu.Value = 'Warning! This is the end of the frame. If there is more time point, please initialize in first & last timepoint and click RESET button.';
                    return;
                end
            end
        end

        % Button pushed function: Button_RUN
        function Button_RUNPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            cellsize = app.EditField_Cell.Value;
            celldilatesize = 5;
            
            fileformat = app.ListBox_Filetype.Value;
            sourcefolder = app.EditField_filepath.Value;
            row = app.Selected_Row.Value;
            col = app.Selected_Column.Value;
            field = app.Selected_Field.Value;
            plane = app.Selected_Plane.Value;
            channel = app.Selected_Channel.Value;
            tpfirst = app.Selected_Timepoint_First.Value;
            tplast = app.Selected_Timepoint_Last.Value;
            thmin = app.EditField_Thres_Min.Value;
            thmax = app.EditField_Thres_Max.Value;
            box = app.EditField_Outer.Value;
            
            % Disable Plot Options button while dialog is open
            app.Button_RUN.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = dialogcelltracking(app, fileformat, sourcefolder, row, col, field, plane, channel, tpfirst, tplast, thmin, thmax, app.cellno, app.cellpath, app.sisterlist, app.sisterid, box, cellsize, celldilatesize);
            
            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp);
            delete(app);
        end

        % Value changed function: Slider_Frame
        function Slider_FrameValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.Slider_Frame.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = round(value);
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
            app.Selected_Timepoint.Value = tp;
        end

        % Value changing function: Slider_Frame
        function Slider_FrameValueChanging(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            changingValue = event.Value;
            % Show image
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = round(changingValue);
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
                
                if ~isempty(app.cellno)
                    if tp > numel(app.cellno)
                        app.commu.Value = sprintf('Timepoint is out of limit (%d)',numel(app.cellno)); app.Lamp.Color = 'y'; pause(0.0001);
                    else
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                    end
                end
            end
            app.Selected_Timepoint.Value = tp;
        end

        % Value changed function: EditField_Sister
        function EditField_SisterValueChanged(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            value = app.EditField_Sister.Value;
            
            myid = regexp(app.ListBox_Cells.Value,'-?\d+','match');
            id = str2double(myid{1});
            
            for t = app.Selected_Timepoint_First.Value:app.Selected_Timepoint_Last.Value
                app.sisterlist{t}(id,:) = str2num(value);
            end
            
            tp = app.Selected_Timepoint.Value;
            
            for i = 1:length(app.cellno{tp})
                posi = app.cellpath{tp}(i,:);
                sis = app.sisterlist{tp}(i,:);
                coordtopoint(app,tp,i,posi,1);
                app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
            end

            app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            % Check the value of the pressed key and perform actions accordingly
            switch key
                case 'a'
                    Button_PreviousPushed(app, event);
                case 'd'
                    Button_NextPushed(app, event);
                case 'q'
                    Button_TM_ReversePushed(app, event);
                case 'e'
                    Button_TM_ForwardPushed(app, event);
                case 'z'
                    Button_WID_ReversePushed(app, event);
                case 'c'
                    Button_WID_ForwardPushed(app, event);
                case 'f'
                    Button_OPTPushed(app, event);
                case 'g'
                    Button_GenSisPushed(app, event);
                case 'h'
                    Button_MANUALPushed(app, event);
                case 't'
                    Button_TerminatePushed(app, event);
                case 'u'
                    Button_filepathPushed(app, event);
                case 'i'
                    Button_LOADPushed(app, event);
                case 'o'
                    Button_SAVEPushed(app, event);
                case 'p'
                    Button_RESETPushed(app, event);
                otherwise
                    % Do nothing for other keys
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FileProcessingPanel = uipanel(app.UIFigure);
            app.FileProcessingPanel.Title = 'File Processing';
            app.Lamp = uilamp(app.FileProcessingPanel);
            app.Button_filepath = uibutton(app.FileProcessingPanel, 'push');
            app.Button_filepath.ButtonPushedFcn = createCallbackFcn(app, @Button_filepathPushed, true);
            app.Button_filepath.Icon = fullfile(pathToMLAPP, 'icons', 'Folder-icon.png');
            app.Button_filepath.Text = '';
            app.RowEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.RowEditFieldLabel.Text = 'Row';
            app.Selected_Row = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Row.Limits = [1 Inf];
            app.Selected_Row.ValueChangedFcn = createCallbackFcn(app, @Selected_RowValueChanged, true);
            app.Selected_Row.Value = 1;
            app.ColumnEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.Selected_Column = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Column.Limits = [1 Inf];
            app.Selected_Column.ValueChangedFcn = createCallbackFcn(app, @Selected_ColumnValueChanged, true);
            app.Selected_Column.Value = 1;
            app.FieldEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.FieldEditFieldLabel.Text = 'Field';
            app.Selected_Field = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Field.Limits = [1 Inf];
            app.Selected_Field.ValueChangedFcn = createCallbackFcn(app, @Selected_FieldValueChanged, true);
            app.Selected_Field.Value = 1;
            app.PlaneEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.PlaneEditFieldLabel.Text = 'Plane';
            app.Selected_Plane = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Plane.Limits = [1 Inf];
            app.Selected_Plane.ValueChangedFcn = createCallbackFcn(app, @Selected_PlaneValueChanged, true);
            app.Selected_Plane.Value = 1;
            app.ChannelEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.Selected_Channel = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Channel.Limits = [1 Inf];
            app.Selected_Channel.ValueChangedFcn = createCallbackFcn(app, @Selected_ChannelValueChanged, true);
            app.Selected_Channel.Value = 1;
            app.EditField_filepath = uieditfield(app.FileProcessingPanel, 'text');
            app.FirstTPLabel = uilabel(app.FileProcessingPanel);
            app.FirstTPLabel.Text = 'First TP';
            app.Selected_Timepoint_First = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Timepoint_First.Limits = [1 Inf];
            app.Selected_Timepoint_First.ValueChangedFcn = createCallbackFcn(app, @Selected_Timepoint_FirstValueChanged, true);
            app.Selected_Timepoint_First.Value = 1;
            app.LastTPLabel = uilabel(app.FileProcessingPanel);
            app.LastTPLabel.Text = 'Last TP';
            app.Selected_Timepoint_Last = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Timepoint_Last.Limits = [1 Inf];
            app.Selected_Timepoint_Last.ValueChangedFcn = createCallbackFcn(app, @Selected_Timepoint_LastValueChanged, true);
            app.Selected_Timepoint_Last.Value = 10;
            app.FileTypeLabel = uilabel(app.FileProcessingPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.ListBox_Filetype = uilistbox(app.FileProcessingPanel);
            app.ListBox_Filetype.Items = {'Columbus Export'};
            app.ListBox_Filetype.ItemsData = {'%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif'};
            app.ListBox_Filetype.Value = '%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif';
            app.TrackingPanel = uipanel(app.UIFigure);
            app.TrackingPanel.Title = 'Tracking';
            app.UIAxes_2 = uiaxes(app.TrackingPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Button_AUTO = uibutton(app.TrackingPanel, 'push');
            app.Button_AUTO.ButtonPushedFcn = createCallbackFcn(app, @Button_AUTOPushed, true);
            app.Button_AUTO.Icon = fullfile(pathToMLAPP, 'icons', 'Record-Normal-Red-icon.png');
            app.Button_AUTO.Text = 'AUTO     ';
            app.Button_MANUAL = uibutton(app.TrackingPanel, 'push');
            app.Button_MANUAL.ButtonPushedFcn = createCallbackFcn(app, @Button_MANUALPushed, true);
            app.Button_MANUAL.Icon = fullfile(pathToMLAPP, 'icons', 'Pencil-icon.png');
            app.Button_MANUAL.Text = {'MANUAL'; ''};
            app.ListBox_Cells = uilistbox(app.TrackingPanel);
            app.ListBox_Cells.Items = {' '};
            app.ListBox_Cells.ValueChangedFcn = createCallbackFcn(app, @ListBox_CellsValueChanged, true);
            app.ListBox_Cells.Value = {};
            app.Button_OPT = uibutton(app.TrackingPanel, 'push');
            app.Button_OPT.ButtonPushedFcn = createCallbackFcn(app, @Button_OPTPushed, true);
            app.Button_OPT.Text = 'OPT';
            app.Button_GenSis = uibutton(app.TrackingPanel, 'push');
            app.Button_GenSis.ButtonPushedFcn = createCallbackFcn(app, @Button_GenSisPushed, true);
            app.Button_GenSis.Text = 'Gen_sis';
            app.Button_DEL = uibutton(app.TrackingPanel, 'push');
            app.Button_DEL.ButtonPushedFcn = createCallbackFcn(app, @Button_DELPushed, true);
            app.Button_DEL.Text = 'Del_IND';
            app.Button_Terminate = uibutton(app.TrackingPanel, 'push');
            app.Button_Terminate.ButtonPushedFcn = createCallbackFcn(app, @Button_TerminatePushed, true);
            app.Button_Terminate.Text = 'Terminate';
            app.ListBox_Restcells = uilistbox(app.TrackingPanel);
            app.ListBox_Restcells.Items = {' '};
            app.ListBox_Restcells.Value = {};
            app.Button_ReserveAll = uibutton(app.TrackingPanel, 'push');
            app.Button_ReserveAll.ButtonPushedFcn = createCallbackFcn(app, @Button_ReserveAllPushed, true);
            app.Button_ReserveAll.Text = 'Reserve_All';
            app.Button_RestoreAll = uibutton(app.TrackingPanel, 'push');
            app.Button_RestoreAll.ButtonPushedFcn = createCallbackFcn(app, @Button_RestoreAllPushed, true);
            app.Button_RestoreAll.Text = 'Restore_All';
            app.Button_RestoreIND = uibutton(app.TrackingPanel, 'push');
            app.Button_RestoreIND.ButtonPushedFcn = createCallbackFcn(app, @Button_RestoreINDPushed, true);
            app.Button_RestoreIND.Text = 'Restore_IND';
            app.CellNoEditFieldLabel = uilabel(app.TrackingPanel);
            app.CellNoEditFieldLabel.Text = 'Cell No.';
            app.EditField_CellNo = uieditfield(app.TrackingPanel, 'numeric');
            app.EditField_CellNo.Limits = [1 Inf];
            app.EditField_CellNo.Value = 1;
            app.CoordsEditFieldLabel = uilabel(app.TrackingPanel);
            app.CoordsEditFieldLabel.Text = 'Coords.';
            app.EditField_Coords = uieditfield(app.TrackingPanel, 'text');
            app.EditField_Coords.Value = '(x,y)';
            app.SisterEditFieldLabel = uilabel(app.TrackingPanel);
            app.SisterEditFieldLabel.Text = 'Sister';
            app.EditField_Sister = uieditfield(app.TrackingPanel, 'text');
            app.EditField_Sister.ValueChangedFcn = createCallbackFcn(app, @EditField_SisterValueChanged, true);
            app.EditField_Sister.Value = '-1 -1 -1';
            app.commu = uieditfield(app.UIFigure, 'text');
            app.MainPanel = uipanel(app.UIFigure);
            app.Button_RESET = uibutton(app.MainPanel, 'push');
            app.Button_RESET.ButtonPushedFcn = createCallbackFcn(app, @Button_RESETPushed, true);
            app.Button_RESET.Icon = fullfile(pathToMLAPP, 'icons', 'Clear-icon.png');
            app.Button_RESET.Text = 'RESET';
            app.Button_LOAD = uibutton(app.MainPanel, 'push');
            app.Button_LOAD.ButtonPushedFcn = createCallbackFcn(app, @Button_LOADPushed, true);
            app.Button_LOAD.Icon = fullfile(pathToMLAPP, 'icons', 'Wait-icon.png');
            app.Button_LOAD.Text = 'LOAD';
            app.Button_SAVE = uibutton(app.MainPanel, 'push');
            app.Button_SAVE.ButtonPushedFcn = createCallbackFcn(app, @Button_SAVEPushed, true);
            app.Button_SAVE.Icon = fullfile(pathToMLAPP, 'icons', 'floppy-icon.png');
            app.Button_SAVE.Text = 'SAVE';
            app.Button_RUN = uibutton(app.MainPanel, 'push');
            app.Button_RUN.ButtonPushedFcn = createCallbackFcn(app, @Button_RUNPushed, true);
            app.Button_RUN.Icon = fullfile(pathToMLAPP, 'icons', 'rocket.png');
            app.Button_RUN.Text = 'RUN';
            app.ProcessPanel = uipanel(app.UIFigure);
            app.ProcessPanel.Title = 'Image Player';
            app.CurrentEditFieldLabel = uilabel(app.ProcessPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.Selected_Timepoint = uieditfield(app.ProcessPanel, 'numeric');
            app.Selected_Timepoint.Limits = [1 Inf];
            app.Selected_Timepoint.ValueChangedFcn = createCallbackFcn(app, @Selected_TimepointValueChanged, true);
            app.Selected_Timepoint.Value = 1;
            app.Button_Play = uibutton(app.ProcessPanel, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayPushed, true);
            app.Button_Play.Icon = fullfile(pathToMLAPP, 'icons', 'play-icon.png');
            app.Button_Play.Text = '';
            app.Button_Pause = uibutton(app.ProcessPanel, 'push');
            app.Button_Pause.ButtonPushedFcn = createCallbackFcn(app, @Button_PausePushed, true);
            app.Button_Pause.Icon = fullfile(pathToMLAPP, 'icons', 'pause-icon.png');
            app.Button_Pause.Text = '';
            app.Button_Previous = uibutton(app.ProcessPanel, 'push');
            app.Button_Previous.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviousPushed, true);
            app.Button_Previous.Icon = fullfile(pathToMLAPP, 'icons', 'rewind-icon.png');
            app.Button_Previous.Text = '';
            app.Button_Next = uibutton(app.ProcessPanel, 'push');
            app.Button_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_NextPushed, true);
            app.Button_Next.Icon = fullfile(pathToMLAPP, 'icons', 'forward-icon.png');
            app.Button_Next.Text = '';
            app.Button_First = uibutton(app.ProcessPanel, 'push');
            app.Button_First.ButtonPushedFcn = createCallbackFcn(app, @Button_FirstPushed, true);
            app.Button_First.Icon = fullfile(pathToMLAPP, 'icons', 'first-track-icon.png');
            app.Button_First.Text = '';
            app.Button_Last = uibutton(app.ProcessPanel, 'push');
            app.Button_Last.ButtonPushedFcn = createCallbackFcn(app, @Button_LastPushed, true);
            app.Button_Last.Icon = fullfile(pathToMLAPP, 'icons', 'last-track-icon.png');
            app.Button_Last.Text = '';
            app.AdjustPanel = uipanel(app.UIFigure);
            app.AdjustPanel.Title = 'Adjusting';
            app.DisplaythresholdLabel = uilabel(app.AdjustPanel);
            app.DisplaythresholdLabel.Text = 'Display threshold';
            app.EditField_Thres_Min = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_Thres_Min.Limits = [0 Inf];
            app.EditField_Thres_Min.ValueChangedFcn = createCallbackFcn(app, @EditField_Thres_MinValueChanged, true);
            app.EditField_Thres_Max = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_Thres_Max.Limits = [0 Inf];
            app.EditField_Thres_Max.ValueChangedFcn = createCallbackFcn(app, @EditField_Thres_MaxValueChanged, true);
            app.EditField_Thres_Max.Value = 1;
            app.Button_AutoThres = uibutton(app.AdjustPanel, 'push');
            app.Button_AutoThres.ButtonPushedFcn = createCallbackFcn(app, @Button_AutoThresPushed, true);
            app.Button_AutoThres.Text = 'Auto Level';
            app.Button_AlwayThres = uibutton(app.AdjustPanel, 'state');
            app.Button_AlwayThres.ValueChangedFcn = createCallbackFcn(app, @Button_AlwayThresValueChanged, true);
            app.Button_AlwayThres.Text = '';
            app.EditField_CellSize_Min = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_CellSize_Min.Limits = [0 Inf];
            app.EditField_CellSize_Min.Value = 15;
            app.EditField_CellSize_Max = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_CellSize_Max.Limits = [0 Inf];
            app.EditField_CellSize_Max.Value = 100;
            app.CellSizeLabel = uilabel(app.AdjustPanel);
            app.CellSizeLabel.Text = 'Cell Size';
            app.SimilarityLabel = uilabel(app.AdjustPanel);
            app.SimilarityLabel.Text = 'Similarity';
            app.EditField_Similarity = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_Similarity.Limits = [0 1];
            app.EditField_Similarity.Value = 0.9;
            app.SwitchBoxLabel = uilabel(app.AdjustPanel);
            app.SwitchBoxLabel.Text = 'SwitchBox';
            app.Switch_Box = uiswitch(app.AdjustPanel, 'slider');
            app.Switch_Box.ValueChangedFcn = createCallbackFcn(app, @Switch_BoxValueChanged, true);
            app.CellLabel = uilabel(app.AdjustPanel);
            app.CellLabel.Text = 'Cell';
            app.EditField_Cell = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_Cell.Limits = [0 Inf];
            app.EditField_Cell.Value = 30;
            app.OuterLabel = uilabel(app.AdjustPanel);
            app.OuterLabel.Text = 'Outer';
            app.EditField_Outer = uieditfield(app.AdjustPanel, 'numeric');
            app.EditField_Outer.Limits = [0 Inf];
            app.EditField_Outer.Value = 60;
            app.TemplateMatchingPanel = uipanel(app.UIFigure);
            app.TemplateMatchingPanel.Title = 'Template Matching';
            app.Button_TM_Reverse = uibutton(app.TemplateMatchingPanel, 'push');
            app.Button_TM_Reverse.ButtonPushedFcn = createCallbackFcn(app, @Button_TM_ReversePushed, true);
            app.Button_TM_Reverse.Icon = fullfile(pathToMLAPP, 'icons', 'Button-Previous-icon.png');
            app.Button_TM_Reverse.Text = '';
            app.Button_TM_Forward = uibutton(app.TemplateMatchingPanel, 'push');
            app.Button_TM_Forward.ButtonPushedFcn = createCallbackFcn(app, @Button_TM_ForwardPushed, true);
            app.Button_TM_Forward.Icon = fullfile(pathToMLAPP, 'icons', 'Button-Next-icon.png');
            app.Button_TM_Forward.Text = '';
            app.WholeImduplicatePanel = uipanel(app.UIFigure);
            app.WholeImduplicatePanel.Title = 'Whole Im duplicate';
            app.Button_WID_Reverse = uibutton(app.WholeImduplicatePanel, 'push');
            app.Button_WID_Reverse.ButtonPushedFcn = createCallbackFcn(app, @Button_WID_ReversePushed, true);
            app.Button_WID_Reverse.Icon = fullfile(pathToMLAPP, 'icons', 'Button-Previous-icon.png');
            app.Button_WID_Reverse.Text = '';
            app.Button_WID_Forward = uibutton(app.WholeImduplicatePanel, 'push');
            app.Button_WID_Forward.ButtonPushedFcn = createCallbackFcn(app, @Button_WID_ForwardPushed, true);
            app.Button_WID_Forward.Icon = fullfile(pathToMLAPP, 'icons', 'Button-Next-icon.png');
            app.Button_WID_Forward.Text = '';
            app.Text01 = uilabel(app.UIFigure);
            app.Text01.Text = 'CellTracking';
            app.Text02 = uilabel(app.UIFigure);
            app.Text02.Text = {'Version. 20-07-2022'; '@ 2020 Kriengkrai Phongkitkarun. All rights reserved.'};
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame';
            app.Slider_Frame = uislider(app.UIFigure);
            app.Slider_Frame.Limits = [1 10];
            app.Slider_Frame.MajorTicks = [];
            app.Slider_Frame.ValueChangedFcn = createCallbackFcn(app, @Slider_FrameValueChanged, true);
            app.Slider_Frame.ValueChangingFcn = createCallbackFcn(app, @Slider_FrameValueChanging, true);
            app.Slider_Frame.MinorTicks = [];
            app.Slider_Frame.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = celltracking

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end