classdef dialogcelltracking < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IndividualCellPanel        matlab.ui.container.Panel
        Button_TIFfile             matlab.ui.control.StateButton
        EditField_BOX              matlab.ui.control.NumericEditField
        BOXEditFieldLabel          matlab.ui.control.Label
        Button_IND_EXPORT          matlab.ui.control.Button
        Selected_IND_Timepoint     matlab.ui.control.NumericEditField
        CurrentEditFieldLabel      matlab.ui.control.Label
        Button_IND_Last            matlab.ui.control.Button
        Button_IND_First           matlab.ui.control.Button
        Button_IND_Next            matlab.ui.control.Button
        Button_IND_Previous        matlab.ui.control.Button
        Button_IND_Pause           matlab.ui.control.Button
        Button_IND_Play            matlab.ui.control.Button
        Spinner_SelectedCell       matlab.ui.control.Spinner
        SelectnoSpinnerLabel       matlab.ui.control.Label
        UIAxes_IND                 matlab.ui.control.UIAxes
        VideoPanel                 matlab.ui.container.Panel
        EditField_SEC              matlab.ui.control.NumericEditField
        SECEditFieldLabel          matlab.ui.control.Label
        EditField_MIN              matlab.ui.control.NumericEditField
        MINEditFieldLabel          matlab.ui.control.Label
        EditField_HR               matlab.ui.control.NumericEditField
        HREditFieldLabel           matlab.ui.control.Label
        Button_GENERATE            matlab.ui.control.Button
        EditField_FPS              matlab.ui.control.NumericEditField
        FramerateEditFieldLabel    matlab.ui.control.Label
        CheckBox_CellNumber        matlab.ui.control.CheckBox
        CheckBox_TimeStamp         matlab.ui.control.CheckBox
        TimeStampLabel             matlab.ui.control.Label
        MaskGeneratingPanel        matlab.ui.container.Panel
        EditField_MaskCH           matlab.ui.control.EditField
        maskingCHEditFieldLabel    matlab.ui.control.Label
        EditField_SegmentCH        matlab.ui.control.EditField
        SegmentedCHEditFieldLabel  matlab.ui.control.Label
        Button_RUN                 matlab.ui.control.Button
        FileProcessingPanel        matlab.ui.container.Panel
        Button_AlwayThres          matlab.ui.control.StateButton
        CellDilateSizeLabel        matlab.ui.control.Label
        EditField_CellDilateSize   matlab.ui.control.NumericEditField
        CellSizeLabel              matlab.ui.control.Label
        EditField_CellSize         matlab.ui.control.NumericEditField
        Button_AutoThres           matlab.ui.control.Button
        EditField_Thres_Max        matlab.ui.control.NumericEditField
        EditField_Thres_Min        matlab.ui.control.NumericEditField
        DisplaythresholdLabel      matlab.ui.control.Label
        Selected_Timepoint         matlab.ui.control.NumericEditField
        CurrentLabel               matlab.ui.control.Label
        Selected_Timepoint_Last    matlab.ui.control.NumericEditField
        LastTPLabel                matlab.ui.control.Label
        Selected_Timepoint_First   matlab.ui.control.NumericEditField
        FirstTPLabel               matlab.ui.control.Label
        Selected_Channel           matlab.ui.control.NumericEditField
        ChannelEditFieldLabel      matlab.ui.control.Label
        Selected_Plane             matlab.ui.control.NumericEditField
        PlaneEditFieldLabel        matlab.ui.control.Label
        Selected_Field             matlab.ui.control.NumericEditField
        FieldEditFieldLabel        matlab.ui.control.Label
        Selected_Column            matlab.ui.control.NumericEditField
        ColumnEditFieldLabel       matlab.ui.control.Label
        Selected_Row               matlab.ui.control.NumericEditField
        RowEditFieldLabel          matlab.ui.control.Label
        ListBox_Filetype           matlab.ui.control.ListBox
        FileTypeLabel              matlab.ui.control.Label
        EditField_filepath         matlab.ui.control.EditField
        Lamp                       matlab.ui.control.Lamp
        ProcessPanel               matlab.ui.container.Panel
        Button_Last                matlab.ui.control.Button
        Button_First               matlab.ui.control.Button
        Button_Next                matlab.ui.control.Button
        Button_Previous            matlab.ui.control.Button
        Button_Pause               matlab.ui.control.Button
        Button_Play                matlab.ui.control.Button
        Slider_Frame               matlab.ui.control.Slider
        commu                      matlab.ui.control.EditField
        Text02                     matlab.ui.control.Label
        CellTrackingVideoLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (SetObservable)
        img % Description
    end
    
    properties (Access = private)
        el % Listener of AlwayThres
        CallingApp   % Main app object
        stop % Description
        cellno % Description
        cellpath % Description
        sisterlist % Description
        sisterid % Description
        ROI % Description
        stop_ind % Description
    end
    
    methods (Access = private)
        
        function outputim = loadimage(app,filetype,fileformat,imlocation,tp,SourceF)
            % This function is loading image from Columbus extension
            % outputim = loadimage(fileformat,imlocation,tp,SourceF)
            % fileformat is '%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif'
            % imlocation is a vector contains : [ Row Column Field Plane Channel ]
            % tp         is a selected timepoint
            % SourceF    is a path that stores the image
            
            row = imlocation(1);
            col = imlocation(2);
            field = imlocation(3);
            plane = imlocation(4);
            channel = imlocation(5);
            
            outputim = [];
            
            if filetype == 4
                filename = sprintf(fileformat,row,col,field,tp,plane,channel);
            end
            
            if exist(fullfile(SourceF,filename),'file')
                outputim = imread(fullfile(SourceF,filename));
            else
                app.commu.Value = sprintf('"%s" file not found!',filename);
                app.Lamp.Color = 'r';
                return;
            end
        end
        
        function maskgen_commandline(app,filetype,fileformat,SourceF,row,col,field,plane,templateCH,segmentCH,tps,cellsize,celldilatesize)
            firsttp = tps(1);
            lasttp = tps(end);
            
            H5filename = ['H5OUT_r' num2str(row) '_c' num2str(col) '.h5'];
            cellpath_name = ['/field' num2str(field) '/cellpath'];
            
            if ~exist(fullfile(SourceF,H5filename),'file')
                app.commu.Value = [H5filename ' does not exist.']; app.Lamp.Color = 'r'; pause(0.0001);
                return;
            else
                fileattrib(fullfile(SourceF,H5filename),'+w');
            end
            
            % --- Collect Mask Cell
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if H5L.exists(fid,cellpath_name,'H5P_DEFAULT')
                H5F.close(fid);
                cellpathinfo = h5info(fullfile(SourceF,H5filename), cellpath_name);
                cellpath_mat = h5read(fullfile(SourceF,H5filename),cellpath_name,[1 1 1], [cellpathinfo.Dataspace.Size(1) cellpathinfo.Dataspace.Size(2) cellpathinfo.Dataspace.Size(3)]);
                
                for tp=firsttp:lasttp
                    if ~isempty(find(cellpath_mat(:,:,tp) > 0 ,1))
                        cellpaths{tp} = cellpath_mat(:,:,tp);
                    end
                end
            else
                cellpaths = [];
                app.commu.Value = [H5filename ' does not contains "cellpath".']; app.Lamp.Color = 'r'; pause(0.0001);
                return;
            end
            clear cellpath_mat;
            datasetname = ['/field' num2str(field) '/segmentsCH' num2str(templateCH)];
            selectedcells_name = ['/field' num2str(field) '/selectedcells'];
            fileattrib(fullfile(SourceF,H5filename),'+w');
            
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if ~H5L.exists(fid,datasetname,'H5P_DEFAULT')
                H5F.close(fid);
                app.commu.Value = ['Initializing ' H5filename ':' datasetname]; pause(0.0001);
            else
                H5L.delete(fid,datasetname,'H5P_DEFAULT');
                H5F.close(fid);
                app.commu.Value = ['Overwriting ' H5filename ':' datasetname]; pause(0.0001);
            end
            h5create(fullfile(SourceF,H5filename), datasetname, [size(cellpaths{lasttp},1), lasttp, 3, 2*cellsize+1, 2*cellsize+1], 'Datatype', 'uint8', 'ChunkSize', [1, 1, 3, 2*cellsize+1, 2*cellsize+1], 'Deflate', 9);
            
            % --- Initialize Mask Nuc , Cyt , Cell
            selected_cells = [];
            templateinfo = loadimageinfo(app,filetype,fileformat,[row col field plane templateCH],firsttp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            %CellInd = find(cellpath{lasttp}(:,1)~=-1 & cellpath{lasttp}(:,2)~=-1)';
            
            
            % --- Initialize Intensity Nuc , Cyt , Cell
            % Check exist signal
            basename='outputsignal';
            info = h5info(fullfile(SourceF,H5filename),['/field' num2str(field)]);
            if length(info.Datasets)>0 %#ok<*ISMT>
                for i = 1:length(info.Datasets) %length(info.Datasets):-1:1
                    outputsignalname = info.Datasets(i).Name;
                    tmp = regexp(outputsignalname, ['(?<=' basename ')\d+'], 'match');
                    if ~isempty(tmp)
                        fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
                        H5L.delete(fid,['/field' num2str(field) '/outputsignal' tmp{1}],'H5P_DEFAULT');
                        H5F.close(fid);
                        app.commu.Value = ['Deleting r' num2str(row) 'c' num2str(col) 'f' num2str(field) '-' outputsignalname]; pause(0.0001);
                    end
                end
            end
            
            for ch = 1:numel(segmentCH)
                signal_name = ['/field' num2str(field) '/outputsignal' num2str(segmentCH(ch))];
                
                
                MeasurementProperty = {'Area';'Circularity';'Eccentricity';'EulerNumber';'MaxIntensity';'MeanIntensity';'MinIntensity';'SumIntensity'};
                MeasurementArea     = {'NUCLEI';'CELL';'CYTOSOL'};
                
                mysignal = zeros(size(app.cellpath{lasttp},1),lasttp-firsttp+1,24);
                %mysignal = zeros(1,1,24);
                
                h5create(fullfile(SourceF,H5filename), signal_name, [size(mysignal,1), size(mysignal,2), size(mysignal,3)], 'Datatype', 'double', 'ChunkSize', [size(mysignal,1), size(mysignal,2), 1], 'Deflate', 9);
                %h5write(fullfile(SourceF,H5filename), signal_name, mysignal, [1 1 1], [size(mysignal,1) size(mysignal,2) 21]);
                %h5writeatt(fullfile(SourceF,H5filename),signal_name,'outputsignal_name',outputsignalname);
                
                mpi = 0;
                for ma = 1:numel(MeasurementArea)
                    for mp = 1:numel(MeasurementProperty)
                        mpi = mpi + 1;
                        h5writeatt(fullfile(SourceF,H5filename),signal_name,sprintf('signal%02d',mpi),[MeasurementArea{ma} '-' MeasurementProperty{mp}]);
                    end
                end
            end
            
            % --- Run Collect both mask and intensity
            for cellNo = 1:size(cellpaths{lasttp},1)
                for t = firsttp:lasttp
                    c_cellpath(t,:) = cellpaths{t}(cellNo,:);
                end
                app.commu.Value = ['r' num2str(row) 'c' num2str(col) 'f' num2str(field) ',Processing cell:' num2str(cellNo)]; pause(0.0001);
                
                newsegments = zeros(firsttp,lasttp,3,2*cellsize+1,2*cellsize+1, 'uint8');
                
                posFrameInd = 0;
                for tp = firsttp:lasttp
                    
                    if c_cellpath(tp,1)>0 && c_cellpath(tp,2)>0 && c_cellpath(tp,1)<= imwidth && c_cellpath(tp,2) <= imheight
                        xL=max(c_cellpath(tp,1)-cellsize,1);
                        xR=min(c_cellpath(tp,1)+cellsize,imwidth);
                        yL=max(c_cellpath(tp,2)-cellsize,1);
                        yR=min(c_cellpath(tp,2)+cellsize,imheight);
                        imlocation = [row col field plane templateCH ];
                        
                        if xR-xL == cellsize*2
                            borderX = 1:(cellsize*2+1);
                        elseif xR == imwidth
                            shiftX = imwidth-xL;
                            borderX = 1:(shiftX+1);
                        elseif xL == 1
                            shiftX = cellsize*2+1-xR;
                            borderX = (xL+shiftX):(cellsize*2+1);
                        end
                        
                        if yR-yL == cellsize*2
                            borderY = 1:(cellsize*2+1);
                        elseif yR == imheight
                            shiftY = imheight-yL;
                            borderY = 1:(shiftY+1);
                        elseif yL == 1
                            shiftY = cellsize*2+1-yR;
                            borderY = (yL+shiftY):(cellsize*2+1);
                        end
                        
                        template = zeros(2*cellsize+1,2*cellsize+1);
                        template(borderY,borderX) = loadimage2(app,filetype,fileformat,imlocation,tp,{[yL yR], [xL xR]},SourceF);
                        
                        [~,~,BW] = templateToCentroid(app,template);
                        %[~,~,BWcontour] = templateToCentroid3(app,template);
                        if ~isempty(find(BW==1,1))
                            posFrameInd = posFrameInd+1;
                            
                            mask1 = BW; % nuclei
                            mask2 = bwmorph(BW,'dilate',celldilatesize); % cell
                            mask3 = bwmorph(squeeze(mask2),'erode',1) & ~squeeze(mask1); % cytosol
                            
                            newsegments(1,tp,1,:,:) = mask1; % nuclei
                            newsegments(1,tp,2,:,:) = mask2; % cell
                            newsegments(1,tp,3,:,:) = mask3; % cytosol
                            
                            for ch = 1:numel(segmentCH)
                                signal_name = ['/field' num2str(field) '/outputsignal' num2str(segmentCH(ch))];
                                imlocation = [row col field plane segmentCH(ch)];
                                grayImage = zeros(2*cellsize+1,2*cellsize+1);
                                grayImage(borderY,borderX) = loadimage2(app,filetype,fileformat,imlocation,tp,{[yL yR], [xL xR]},SourceF);
                                
                                
                                props1 = regionprops(mask1, grayImage, 'all');
                                props2 = regionprops(mask2, grayImage, 'all');
                                props3 = regionprops(mask3, grayImage, 'all');
                                
                                mpi = 0;
                                for ma = 1:numel(MeasurementArea)
                                    for mp = 1:numel(MeasurementProperty)
                                        mpi = mpi + 1;
                                        switch ma
                                            case 1
                                                if mp ~= numel(MeasurementProperty)
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = props1.(MeasurementProperty{mp});
                                                else
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = sum(grayImage(logical(mask1)));
                                                end
                                            case 2
                                                if mp ~= numel(MeasurementProperty)
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = props2.(MeasurementProperty{mp});
                                                else
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = sum(grayImage(logical(mask2)));
                                                end
                                            case 3
                                                if mp ~= numel(MeasurementProperty)
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = props3.(MeasurementProperty{mp});
                                                else
                                                    mysignal(cellNo,tp-firsttp+1,mpi) = sum(grayImage(logical(mask3)));
                                                end
                                        end
                                    end
                                end
                                h5write(fullfile(SourceF,H5filename), signal_name, mysignal(cellNo,tp-firsttp+1,:), [cellNo tp-firsttp+1 1], [1 1 size(mysignal,3)]);
                                
                            end
                        end
                    end
                    
                end
                h5write(fullfile(SourceF,H5filename), datasetname, newsegments, [cellNo firsttp 1 1 1], [1 lasttp-firsttp+1 3 2*cellsize+1 2*cellsize+1]);
                
                if posFrameInd > 0.2*length(cellpaths)
                    selected_cells = [selected_cells;cellNo];
                end
            end
            
            % --- Collect Selected Cell
            fid = H5F.open(fullfile(SourceF,H5filename),'H5F_ACC_RDWR','H5P_DEFAULT');
            if ~H5L.exists(fid,selectedcells_name,'H5P_DEFAULT')
                H5F.close(fid);
                app.commu.Value = ['Initializing ' H5filename ':' selectedcells_name]; pause(0.0001);
            else
                H5L.delete(fid,selectedcells_name,'H5P_DEFAULT');
                H5F.close(fid);
                app.commu.Value = ['Overwriting ' H5filename ':' selectedcells_name]; pause(0.0001);
            end
            
            h5create(fullfile(SourceF,H5filename), selectedcells_name, length(selected_cells), 'Datatype', 'uint32');
            h5write(fullfile(SourceF,H5filename), selectedcells_name, uint32(selected_cells));
            
        end
        
        function [x,y,BW] = templateToCentroid(app,M)
            BWc = zeros(size(M));
            for i=1.2:0.6:4
                edgedIm = edge(M,'canny',0,i);
                BW = imfill(edgedIm,'holes');
                BW = bwmorph(BW,'open',1);
                BW = bwselect(BW,round(size(M,2)/2),round(size(M,1)/2));
                BWc = BWc | BW;
            end
            %figure(1),imshow(BWc)
            S  = regionprops(BWc, 'centroid');
            BW = zeros(size(M));
            if isempty(find(BWc==0, 1)) || isempty(find(BWc==1, 1))
                x = round(size(M,2)/2);
                y = round(size(M,1)/2);
            else
                x = round(S.Centroid(1));
                y = round(S.Centroid(2));
                [Ys,Xs] = find(BWc==1);
                xshift = x-round(size(M,2)/2);
                yshift = y-round(size(M,1)/2);
                if isempty(find( (Ys-yshift)<=0 | (Ys-yshift)>size(M,1) | (Xs-xshift)<=0 | (Xs-xshift)>size(M,2),1))
                    for i=1:length(Ys)
                        BW(Ys(i)-yshift,Xs(i)-xshift) = 1;
                    end
                else
                    BW = BWc;
                end
            end
        end
        
        function outputim = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF)
            row = imlocation(1);
            col = imlocation(2);
            field = imlocation(3);
            plane = imlocation(4);
            channel = imlocation(5);
            
            switch filetype
                case 4
                    filename = sprintf(fileformat,row,col,field,tp,plane,channel);
                    outputim = imread(fullfile(SourceF,filename),'PixelRegion',pixelsreg);
                    %case 1
                    %    filename = sprintf(fileformat,row,col,field,plane,channel,tp);
                    %    outputim = imread(fullfile(SourceF,filename),'PixelRegion',pixelsreg);
                    %case 2
                    %    outputim = imread(fullfile(SourceF,fileformat),'Index',totalCH*(tp-1)+channel,'PixelRegion',pixelsreg);
                    %case 3
                    %    filename = sprintf(fileformat,channelnames{channel},tp);
                    %    outputim = imread(fullfile(SourceF,filename),'PixelRegion',pixelsreg);
            end
            
        end
        
        function iminfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF)
            row = imlocation(1);
            col = imlocation(2);
            field = imlocation(3);
            plane = imlocation(4);
            channel = imlocation(5);
            
            switch filetype
                %case 1
                %    filename = sprintf(fileformat,row,col,field,plane,channel,tp);
                %    iminfo = imfinfo(fullfile(SourceF,filename));
                %case 2
                %    iminfo = imfinfo(fullfile(SourceF,fileformat),'Index',totalCH*(tp-1)+channel);
                %case 3
                %    filename = sprintf(fileformat,channelnames{channel},tp);
                %    iminfo = imfinfo(fullfile(SourceF,filename));
                case 4
                    filename = sprintf(fileformat,row,col,field,tp,plane,channel);
                    iminfo = imfinfo(fullfile(SourceF,filename));
            end
        end


        function coordtopoint(app,tp,id,coord,labellogic)
            % To add point of coordinate to the image
            % p = coordtopoint(app,tp,id,coord,color,labellogic)
            % tp is timepoint
            % id is no. of object
            % coord is a vector contains [ x y ]
            % color can be 'r' or [ 1 0 0 ]
            % axis (in this case is use handles.axes1)
            % labellogic is a mode for draw if 0=auto , 1=manual
            
            if labellogic == 1 % auto point
                
                check = app.sisterid(id,:); % check if mother , sister ?
                
                if check(1) == 0 % Non-dividing object
                    color = [99 255 92]./255;
                    
                    app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Position', coord, ...
                    'Color', color, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on' );
                    
                elseif check(1) == 1 && tp >= check(2) % Dividing object
                    color = [253 101 108]./255;
                    
                    app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Position', coord, ...
                    'Color', color, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on' );
                
                elseif check(1) == 1 && tp < check(2) % Dividing object before tp
                    return;
                end
                
            elseif labellogic == 2 % manual point
                app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Color', [99 255 92]./255, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on' );
                
            elseif labellogic == 3 % manual sister point
                app.ROI{id} = drawpoint(app.UIAxes, ...
                    'Color', [253 101 108]./255, ...
                    'InteractionsAllowed', 'all', ...
                    'Label', num2str(id), ...
                    'LabelVisible', 'on' );
                
            end
            
            %'SelectedColor', [179 118 255]./255, ...
            
            % labellogic is a label visibility if 0=hide , 1=show
            
            %if labellogic
            %    labelvis = 'on';
            %else
            %    labelvis = 'off';
            %end
            
            
            %addlistener(app.ROI{id},'MovingROI',@allevents);
            addlistener(app.ROI{id},'ROIMoved',@allevents);
            
            function allevents(src,evt)
                evname = evt.EventName;
                switch(evname)
                    %case{'MovingROI'}
                    %prev = evt.PreviousPosition;
                    %curr = round(evt.CurrentPosition);
                    case{'ROIMoved'}
                        %delete(app.ROI{id});
                        app.ROI{id} = src;
                        prev = evt.PreviousPosition;
                        curr = round(evt.CurrentPosition);
                        app.cellpath{tp}(id,:) = curr;
                        sis = app.sisterlist{tp}(id,:);
                        app.ListBox_Cells.Items{id} = num2str([ id curr sis ]);
                        
                        % Update sister
                        if app.sisterid(id,1) == 0  % check if mother ?
                            id4 = sis(1);
                            id5 = sis(2);
                            id6 = sis(3);
                            if id4 ~= -1 && tp < app.sisterid(id4,2)
                                app.cellpath{tp}(id4,:) = curr;
                            end
                            if id5 ~= -1 && tp < app.sisterid(id5,2)
                                app.cellpath{tp}(id5,:) = curr;
                            end
                            if id6 ~= -1 && tp < app.sisterid(id6,2)
                                app.cellpath{tp}(id6,:) = curr;
                            end
                        end
                        
                        for i = 1:length(app.cellno{tp})
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                        end
                        
                end
                app.ListBox_Cells.Value = id;
                ListBox_CellsValueChanged(app, event);
            end
        end
        
        function [x,y,BW] = templateToCentroid3(app,M)
            xg = round(size(M,2)/2);
            yg = round(size(M,1)/2);
            M = imadjust(M);
            M(1,:) = 0;
            M(end,:) = 0;
            M(:,1) = 0;
            M(:,end) = 0;
            outBW = modchenvese(M,80,0.05,round(size(M,1)*1));
            se = strel('disk',3);
            outBW = imclose(outBW,se);
            outBW(1,:) = 0;
            outBW(end,:) = 0;
            outBW(:,1) = 0;
            outBW(:,end) = 0;
            
            BW = bwselect(outBW,xg,yg);
            S  = regionprops(BW, 'centroid');
            
            if ~isfield(S,'Centroid') || length(S)>1
                x = xg;
                y = yg;
                
            else
                x = round(S.Centroid(1));
                y = round(S.Centroid(2));
            end


            function seg = modchenvese(I,num_iter,mu,outerbox)
                I = imadjust(I);
                s = outerbox./min(size(I,1),size(I,2)); % resize scale
                
                n = zeros(size(I));
                midY = round(size(n,1)/2);
                midX = round(size(n,2)/2);
                boxsize = round(size(I,1)*0.1);%round(outerbox/2*.9);
                n(midY-boxsize:midY+boxsize,midX-boxsize:midX+boxsize) = 1;
                I = imresize(I,s);
                mask = imresize(n,s);
                
                phi0 = bwdist(mask)-bwdist(1-mask)+im2double(mask)-.5;
                force = eps;
                
                %-- Display settings
                %figure();
                %subplot(2,2,1); imshow(I); title('Input Image');axis equal;
                %subplot(2,2,2); contour(flipud(phi0), [0 0], 'r','LineWidth',1); title('initial contour');axis equal;
                %subplot(2,2,3); title('Segmentation');axis equal;
                %-- End Display original image and mask
                
                %-- Main loop


                for n=1:num_iter
                    inidx = find(phi0>=0); % frontground index
                    outidx = find(phi0<0); % background index
                    force_image = 0; % initial image force for each layer
                    L = im2double(I); % get one image component
                    
                    c1 = sum(sum(L.*Heaviside(phi0)))/(length(inidx)+eps); % average inside of Phi0
                    c2 = sum(sum(L.*(1-Heaviside(phi0))))/(length(outidx)+eps); % verage outside of Phi0
                    force_image=-(L-c1).^2+(L-c2).^2+force_image;
                    % sum Image Force on all components (used for vector image)
                    % if 'chan' is applied, this loop become one sigle code as a
                    % result of layer = 1
                    
                    
                    % calculate the external force of the image
                    force = mu*kappa(phi0)./max(max(abs(kappa(phi0))))+force_image;
                    
                    % normalized the force
                    force = force./(max(max(abs(force))));
                    
                    % get stepsize dt
                    dt=0.5;
                    
                    % get parameters for checking whether to stop
                    old = phi0;
                    phi0 = phi0+dt.*force;
                    new = phi0;
                    indicator = checkstop(old,new,dt);


                    % intermediate output
                    %if(mod(n,20) == 0)
                    %    showphi(I,phi0,n);
                    %end;
                    if indicator % decide to stop or continue
                        %showphi(I,phi0,n);
                        
                        %make mask from SDF
                        seg = phi0<=0; %-- Get mask from levelset
                        
                        midY = round(size(seg,1)/2);
                        midX = round(size(seg,2)/2);
                        if seg(midY,midX) > 0
                            seg = bwselect(seg,midX,midY);
                        else
                            seg = ~seg;
                            seg = bwselect(seg,midX,midY);
                            %seg = bwselect(~seg,midX,midY);
                        end
                        
                        
                        %subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
                        seg = imresize(seg,1/s);
                        return;
                    end
                end;
                %showphi(I,phi0,n);
                
                %make mask from SDF
                seg = phi0<=0; %-- Get mask from levelset
                
                midY = round(size(seg,1)/2);
                midX = round(size(seg,2)/2);
                
                if seg(midY,midX) > 0
                    seg = bwselect(seg,midX,midY);
                else
                    seg = ~seg;
                    seg = bwselect(seg,midX,midY);
                end
                
                
                %subplot(2,2,4); imshow(seg); title('Global Region-Based Segmentation');
                seg = imresize(seg,1/s);
            end
            
            function showphi(I, phi, i)
                % show curve evolution of phi
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                for j = 1:size(phi,3)
                    phi_{j} = phi(:,:,j); %#ok<*AGROW>
                end
                imshow(I,'initialmagnification','fit','displayrange',[0 255]);
                hold on;
                
                if size(phi,3) == 1
                    contour(phi_{1}, [0 0], 'r','LineWidth',4);
                    contour(phi_{1}, [0 0], 'g','LineWidth',1.3);
                else
                    contour(phi_{1}, [0 0], 'r','LineWidth',4);
                    contour(phi_{1}, [0 0], 'x','LineWidth',1.3);
                    contour(phi_{2}, [0 0], 'g','LineWidth',4);
                    contour(phi_{2}, [0 0], 'x','LineWidth',1.3);
                end
                hold off;
                title([num2str(i) ' Iterations']);
                drawnow;
            end
            
            function KG = kappa(I)
                % get curvature information of input image
                % input: 2D image I
                % output: curvature matrix KG
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                I = double(I);
                [m,n] = size(I);
                P = padarray(I,[1,1],1,'pre');
                P = padarray(P,[1,1],1,'post');
                
                % central difference
                fy = P(3:end,2:n+1)-P(1:m,2:n+1);
                fx = P(2:m+1,3:end)-P(2:m+1,1:n);
                fyy = P(3:end,2:n+1)+P(1:m,2:n+1)-2*I;
                fxx = P(2:m+1,3:end)+P(2:m+1,1:n)-2*I;
                fxy = 0.25.*(P(3:end,3:end)-P(1:m,3:end)+P(3:end,1:n)-P(1:m,1:n));
                G = (fx.^2+fy.^2).^(0.5);
                K = (fxx.*fy.^2-2*fxy.*fx.*fy+fyy.*fx.^2)./((fx.^2+fy.^2+eps).^(1.5));
                KG = K.*G;
                KG(1,:) = eps;
                KG(end,:) = eps;
                KG(:,1) = eps;
                KG(:,end) = eps;
                KG = KG./max(max(abs(KG)));
            end
            
            function indicator = checkstop(old,new,dt)
                % indicate whether we should performance further iteraions or stop
                
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                layer = size(new,3);
                
                for i = 1:layer
                    old_{i} = old(:,:,i);
                    new_{i} = new(:,:,i);
                end
                
                if layer
                    ind = find(abs(new)<=.5);
                    M = length(ind);
                    Q = sum(abs(new(ind)-old(ind)))./M;
                    if Q<=dt*.18^2 %#ok<*BDSCI>
                        indicator = 1;
                    else
                        indicator = 0;
                    end
                else
                    ind1 = find(abs(old_{1})<1);
                    ind2 = find(abs(old_{2})<1);
                    M1 = length(ind1);
                    M2 = length(ind2);
                    Q1 = sum(abs(new_{1}(ind1)-old_{1}(ind1)))./M1;
                    Q2 = sum(abs(new_{2}(ind2)-old_{2}(ind2)))./M2;
                    if Q1<=dt*.18^2 && Q2<=dt*.18^2
                        indicator = 1;
                    else
                        indicator = 0;
                    end
                end
                return
            end
            
            function H=Heaviside(z)
                % Heaviside step function (smoothed version)
                % Copyright (c) 2009,
                % Yue Wu @ ECE Department, Tufts University
                % All Rights Reserved
                
                Epsilon=10^(-5);
                H=zeros(size(z,1),size(z,2));
                idx1=find(z>Epsilon);
                idx2=find(z<Epsilon & z>-Epsilon);
                H(idx1)=1; %#ok<*FNDSB>
                for i=1:length(idx2)
                    H(idx2(i))=1/2*(1+z(idx2(i))/Epsilon+1/pi*sin(pi*z(idx2(i))/Epsilon));
                end
            end
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, fileformat, sourcefolder, row, col, field, plane, channel, tpfirst, tplast, thmin, thmax, cellno, cellpath, sisterlist, sisterid, box, cellsize, celldilatesize)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Update UI with input values
            app.cellno = cellno;
            app.cellpath = cellpath;
            app.sisterlist = sisterlist;
            app.sisterid = sisterid;
            
            app.EditField_CellSize.Value = cellsize;
            app.EditField_CellDilateSize.Value = celldilatesize;
            
            app.ListBox_Filetype.Value = fileformat;
            app.EditField_filepath.Value = sourcefolder;
            app.Selected_Row.Value = row;
            app.Selected_Column.Value = col;
            app.Selected_Field.Value = field;
            app.Selected_Plane.Value = plane;
            app.Selected_Channel.Value = channel;
            app.Selected_Timepoint.Value = tpfirst;
            app.Selected_Timepoint_First.Value = tpfirst;
            app.Selected_Timepoint_Last.Value = tplast;
            app.EditField_Thres_Min.Value = thmin;
            app.EditField_Thres_Max.Value = thmax;
            app.EditField_BOX.Value = box;
            
            app.Selected_Timepoint.Limits = [tpfirst tplast];
            app.Slider_Frame.Limits = [tpfirst tplast];
            
            % Show initial image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                    
                    % --- For IND image
                    id = 1;
                    %app.Spinner_SelectedCell.Limits = [ INDid n ];
                    app.Spinner_SelectedCell.Value = id;
                    app.Selected_IND_Timepoint.Value = tp;
                    app.Selected_IND_Timepoint.Limits = [ tpfirst tplast ];
                    
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        
                        %template = app.img(yL:yR,xL:xR);
                        
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                    
                end
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.Button_RUN.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: Selected_Channel
        function Selected_ChannelValueChanged(app, event)
            value = app.Selected_Channel.Value;
            
            % Show initial image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = value;
            tp = app.Selected_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                    
                    id = app.Spinner_SelectedCell.Value;
                    
                    if ismember(id,app.cellno{tp})
                        
                        if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                            
                            cellsize = app.EditField_BOX.Value;
                            
                            xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                            xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                            yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                            yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                            
                            %template = app.img(yL:yR,xL:xR);
                            
                            pixelsreg = {[yL yR], [xL xR]};
                            
                            INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                            
                            % Show image
                            imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                            axis(app.UIAxes_IND,'image');
                        end
                        
                    end


                end
            end
        end

        % Value changed function: Selected_Timepoint
        function Selected_TimepointValueChanged(app, event)
            value = app.Selected_Timepoint.Value;
            
            % Show initial image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                end
            end
        end

        % Button pushed function: Button_AutoThres
        function Button_AutoThresPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            % Show image info
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            if ~isempty(app.img)
                thres = stretchlim(app.img);
                app.EditField_Thres_Min.Value = thres(1);
                app.EditField_Thres_Max.Value = thres(2);
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                    
                    id = app.Spinner_SelectedCell.Value;
                    if ismember(id,app.cellno{tp})
                        if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                            cellsize = app.EditField_BOX.Value;
                            
                            xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                            xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                            yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                            yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                            
                            %template = app.img(yL:yR,xL:xR);
                            
                            pixelsreg = {[yL yR], [xL xR]};
                            
                            INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                            
                            % Show image
                            imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                            axis(app.UIAxes_IND,'image');
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: Button_AlwayThres
        function Button_AlwayThresValueChanged(app, event)
            value = app.Button_AlwayThres.Value;
            if value == 1
                app.Button_AlwayThres.BackgroundColor = [0.00,1.00,0.00];
                %Button_AutoThresPushed(app, event);
                app.el = addlistener(app,'img','PostSet',@allevents);
            else
                app.Button_AlwayThres.BackgroundColor = [0.94,0.94,0.94];
                delete(app.el);
                
            end
            
            function allevents(src,evt)
                thres = stretchlim(app.img);
                app.EditField_Thres_Min.Value = thres(1);
                app.EditField_Thres_Max.Value = thres(2);
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                end
            end
        end

        % Value changed function: EditField_Thres_Min
        function EditField_Thres_MinValueChanged(app, event)
            value = app.EditField_Thres_Min.Value;
            
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            % Show image info
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            if ~isempty(app.img)
                imshow(imadjust((app.img),[value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                    
                    id = app.Spinner_SelectedCell.Value;
                    if ismember(id,app.cellno{tp})
                        if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                            cellsize = app.EditField_BOX.Value;
                            
                            xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                            xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                            yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                            yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                            
                            %template = app.img(yL:yR,xL:xR);
                            
                            pixelsreg = {[yL yR], [xL xR]};
                            
                            INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                            
                            % Show image
                            imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                            axis(app.UIAxes_IND,'image');
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Value changed function: EditField_Thres_Max
        function EditField_Thres_MaxValueChanged(app, event)
            value = app.EditField_Thres_Max.Value;
            
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tp = app.Selected_Timepoint.Value;
            
            % Show image info
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                    
                    id = app.Spinner_SelectedCell.Value;
                    if ismember(id,app.cellno{tp})
                        if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                            cellsize = app.EditField_BOX.Value;
                            
                            xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                            xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                            yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                            yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                            
                            %template = app.img(yL:yR,xL:xR);
                            
                            pixelsreg = {[yL yR], [xL xR]};
                            
                            INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                            
                            % Show image
                            imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                            axis(app.UIAxes_IND,'image');
                        end
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_Play
        function Button_PlayPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            app.stop = 0;
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            
            for tp = app.Selected_Timepoint.Value:app.Selected_Timepoint_Last.Value
                app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
                if ~isempty(app.img)
                    imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                    axis(app.UIAxes,'image');
                    
                    if ~isempty(app.cellno)
                        n = length(app.cellno{tp});
                        for i = 1:n
                            posi = app.cellpath{tp}(i,:);
                            sis = app.sisterlist{tp}(i,:);
                            coordtopoint(app,tp,i,posi,1);
                        end
                    end
                    
                end
                app.Selected_Timepoint.Value = tp;
                app.Slider_Frame.Value = tp;
                app.Lamp.Color = 'g';
                pause(0.5);
                if app.stop
                    app.stop = 0; %rest the button state
                    app.commu.Value = 'Pause!'; pause(0.5); app.commu.Value = '';
                    app.Lamp.Color = [0.9 0.9 0.9];
                    break;
                end
            end
            app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_Pause
        function Button_PausePushed(app, event)
            app.commu.Value = ''; pause(0.0001);
            app.stop = 1; %trigger the pause button state
        end

        % Button pushed function: Button_Previous
        function Button_PreviousPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            if tp > app.Selected_Timepoint_First.Value && tp <= app.Selected_Timepoint_Last.Value
                tp = tp - 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_Next
        function Button_NextPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint.Value;
            if tp >= app.Selected_Timepoint_First.Value && tp < app.Selected_Timepoint_Last.Value
                tp = tp + 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_First
        function Button_FirstPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_First.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Button pushed function: Button_Last
        function Button_LastPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_Last.Value;
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
            app.Slider_Frame.Value = tp;
        end

        % Value changed function: Slider_Frame
        function Slider_FrameValueChanged(app, event)
            value = app.Slider_Frame.Value;
            
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = round(value);
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
        end

        % Value changing function: Slider_Frame
        function Slider_FrameValueChanging(app, event)
            changingValue = event.Value;
            
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = round(changingValue);
            imlocation = [ Row Column Field Plane Channel ];
            app.img = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            if ~isempty(app.img)
                imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes);
                axis(app.UIAxes,'image');
                
                if ~isempty(app.cellno)
                    n = length(app.cellno{tp});
                    for i = 1:n
                        posi = app.cellpath{tp}(i,:);
                        sis = app.sisterlist{tp}(i,:);
                        coordtopoint(app,tp,i,posi,1);
                        app.ListBox_Cells.Items{i} = num2str([ i posi sis ]);
                    end
                end
                
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_Timepoint.Value = tp;
        end

        % Button pushed function: Button_RUN
        function Button_RUNPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % check "cellpath" before run generating mask
            if ~isempty(app.cellpath)
                tpfirst = app.Selected_Timepoint_First.Value;
                tplast = app.Selected_Timepoint_Last.Value;
                checkid = 0;
                for tp = tpfirst:tplast
                    if isempty(app.cellpath{tp})
                        checkid = tp;
                        return;
                    end
                end
                if checkid > 0
                    app.commu.Value = sprintf('Error! Did not find any object in Time : %d. Please check your tracking.',checkid);
                    app.Lamp.Color = 'r';
                    return;
                end
                
                SourceF = app.EditField_filepath.Value;
                row = app.Selected_Row.Value;
                col = app.Selected_Column.Value;
                field = app.Selected_Field.Value;
                plane = app.Selected_Plane.Value;
                
                templateCH = app.EditField_SegmentCH.Value;
                segmentCH = app.EditField_MaskCH.Value;
                
                % check CHANNEL
                sep = split(templateCH,",");
                sep = str2double(sep);
                if isnan(sep)
                    app.commu.Value = 'Error! Segmenting CH should be a number only (e.g. 1).'; app.Lamp.Color = 'r';
                    return;
                else
                    sep(isnan(sep)) = [];
                end
                templateCH = sep;
                
                sep = split(segmentCH,",");
                sep = str2double(sep);
                if isnan(sep)
                    app.commu.Value = 'Error! Masking CH should be a number sequence (e.g. 2,3,4).'; app.Lamp.Color = 'r';
                    return;
                else
                    sep(isnan(sep)) = [];
                end
                segmentCH = sep;
                
                
                tps = tpfirst:tplast;
                filetype = 4;
                fileformat = app.ListBox_Filetype.Value;
                cellsize = app.EditField_CellSize.Value;
                celldilatesize = app.EditField_CellDilateSize.Value;
                
                maskgen_commandline(app,filetype,fileformat,SourceF,row,col,field,plane,templateCH,segmentCH,tps,cellsize,celldilatesize)
                
                
            else
                app.commu.Value = 'Error! Did not find any object. Please check your tracking.'; app.Lamp.Color = 'r';
            end
            app.commu.Value = 'Done Generating Mask.'; app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_GENERATE
        function Button_GENERATEPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tpfirst = app.Selected_Timepoint_First.Value;
            tplast = app.Selected_Timepoint_Last.Value;
            
            SourceF = app.EditField_filepath.Value;
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            row = app.Selected_Row.Value;
            col = app.Selected_Column.Value;
            field = app.Selected_Field.Value;
            plane = app.Selected_Plane.Value;
            channel = app.Selected_Channel.Value;
            
            if isempty(app.img)
                app.commu.Value = 'No input images.'; pause(0.0001);
                return;
            end
            
            app.commu.Value = 'Generating MATLAB movie...'; pause(0.0001);
            
            aviobj = VideoWriter(fullfile(SourceF,['myMov_r' num2str(row) 'c' num2str(col) 'f' num2str(field) 'ch' num2str(channel) '.avi']));
            aviobj.FrameRate = app.EditField_FPS.Value;
            aviobj.Quality = 100;
            open(aviobj);
            f1 = figure(1);
            axis image;
            set(gca,'nextplot','replacechildren');
            set(gcf,'Renderer','zbuffer');
            
            for tp = tpfirst:tplast
                app.commu.Value = sprintf('Generating MATLAB movie (%d %%)...',round(tp/tplast*100)); pause(0.0001);
                
                app.img = loadimage(app,filetype,fileformat,[row col field plane channel],tp,SourceF);
                
                figure(1),imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]),'InitialMagnification',50); hold on;
                
                if app.CheckBox_CellNumber.Value
                    if ~isempty(app.cellpath)
                        for cell = 1:size(app.cellpath{tp},1)
                            coord = [app.cellpath{tp}(cell,1) app.cellpath{tp}(cell,2)];
                            
                            if app.sisterlist{tp}(cell) == -1
                                color = [99 255 92]./255; % Non-dividing object
                                
                            else
                                color = [253 101 108]./255; % Dividing object
                                
                            end
                            plot(app.cellpath{tp}(cell,1),app.cellpath{tp}(cell,2),'o',...
                                'MarkerFaceColor',color,...
                                'MarkerSize',4);
                            
                            text(app.cellpath{tp}(cell,1)+10,app.cellpath{tp}(cell,2)+10,num2str(cell),...
                                'HorizontalAlignment','left',...
                                'VerticalAlignment','middle',...
                                'color',color,...
                                'FontSize',8,...
                                'FontName','Arial',...
                                'FontWeight','bold');
                            
                        end
                    end
                end
                
                if app.CheckBox_TimeStamp.Value
                    hour = floor(1/60*(tp-1)*( app.EditField_HR.Value*60 + app.EditField_MIN.Value + app.EditField_SEC.Value/60 ));
                    minute = mod(floor((tp-1)*( app.EditField_HR.Value*60 + app.EditField_MIN.Value + app.EditField_SEC.Value/60 )),60);
                    second = mod((tp-1)*( app.EditField_HR.Value*60*60 + app.EditField_MIN.Value*60 + app.EditField_SEC.Value),60);
                    
                    text(30,30,[num2str(hour,'%02.0f') ':' num2str(minute,'%02.0f') ':' num2str(second,'%02.0f')],...
                        'HorizontalAlignment','left',...
                        'VerticalAlignment','top',...
                        'color','w',...
                        'BackgroundColor','k',...
                        'fontsize',14);
                end
                
                drawnow;
                F = getframe(gca);
                writeVideo(aviobj,F);
            end
            
            close(aviobj);
            close(f1);
            
            app.commu.Value = ['Done generating: myMov_r' num2str(row) 'c' num2str(col) 'f' num2str(col) 'ch' num2str(channel) '.avi']; app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: Spinner_SelectedCell
        function Spinner_SelectedCellValueChanged(app, event)
            value = app.Spinner_SelectedCell.Value;
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_IND_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = value;
            
            if ~isempty(template)
                
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        
                        %template = app.img(yL:yR,xL:xR);
                        
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                    
                end
            end
            
        end

        % Value changed function: Selected_IND_Timepoint
        function Selected_IND_TimepointValueChanged(app, event)
            value = app.Selected_IND_Timepoint.Value;
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = value;
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        
                        %template = app.img(yL:yR,xL:xR);
                        
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
            end
            
        end

        % Button pushed function: Button_IND_Play
        function Button_IND_PlayPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            app.stop_ind = 0;
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            imlocation = [ Row Column Field Plane Channel ];
            
            for tp = app.Selected_IND_Timepoint.Value:app.Selected_Timepoint_Last.Value
                template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
                templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
                imwidth = templateinfo.Width;
                imheight = templateinfo.Height;
                
                id = app.Spinner_SelectedCell.Value;
                
                if ~isempty(template)
                    if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                        if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                            cellsize = app.EditField_BOX.Value;
                            
                            xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                            xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                            yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                            yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                            pixelsreg = {[yL yR], [xL xR]};
                            
                            INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                            
                            % Show image
                            imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                            axis(app.UIAxes_IND,'image');
                        end
                    end
                end
                app.Selected_IND_Timepoint.Value = tp;
                app.Lamp.Color = 'g';
                pause(0.5);
                if app.stop_ind
                    app.stop_ind = 0; %rest the button state
                    app.commu.Value = 'Pause!'; pause(0.5); app.commu.Value = '';
                    app.Lamp.Color = [0.9 0.9 0.9];
                    break;
                end
            end
            app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Button pushed function: Button_IND_Pause
        function Button_IND_PausePushed(app, event)
            app.commu.Value = ''; pause(0.0001);
            app.stop_ind = 1; %trigger the pause button state
        end

        % Button pushed function: Button_IND_Previous
        function Button_IND_PreviousPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_IND_Timepoint.Value;
            if tp > app.Selected_Timepoint_First.Value && tp <= app.Selected_Timepoint_Last.Value
                tp = tp - 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_IND_Timepoint.Value = tp;
        end

        % Button pushed function: Button_IND_Next
        function Button_IND_NextPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_IND_Timepoint.Value;
            if tp >= app.Selected_Timepoint_First.Value && tp < app.Selected_Timepoint_Last.Value
                tp = tp + 1;
            end
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_IND_Timepoint.Value = tp;
        end

        % Button pushed function: Button_IND_First
        function Button_IND_FirstPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_First.Value;
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_IND_Timepoint.Value = tp;
        end

        % Button pushed function: Button_IND_Last
        function Button_IND_LastPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_Timepoint_Last.Value;
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        cellsize = app.EditField_BOX.Value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
            app.Selected_IND_Timepoint.Value = tp;
        end

        % Value changed function: EditField_BOX
        function EditField_BOXValueChanged(app, event)
            value = app.EditField_BOX.Value;
            
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            % Show image
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            SourceF = app.EditField_filepath.Value;
            Row = app.Selected_Row.Value;
            Column = app.Selected_Column.Value;
            Field = app.Selected_Field.Value;
            Plane = app.Selected_Plane.Value;
            Channel = app.Selected_Channel.Value;
            tp = app.Selected_IND_Timepoint.Value;
            imlocation = [ Row Column Field Plane Channel ];
            template = loadimage(app,filetype,fileformat,imlocation,tp,SourceF);
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if ~isempty(template)
                if ~isempty(app.cellno) && ismember(id,app.cellno{tp})
                    if app.cellpath{tp}(id,1) ~= -1 || app.cellpath{tp}(id,2) ~= -1
                        
                        cellsize = value;
                        
                        xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                        xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                        yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                        yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                        pixelsreg = {[yL yR], [xL xR]};
                        
                        INDframe = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                        
                        % Show image
                        imshow(imadjust((INDframe),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]), 'parent', app.UIAxes_IND);
                        axis(app.UIAxes_IND,'image');
                    end
                end
                app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
            end
        end

        % Button pushed function: Button_IND_EXPORT
        function Button_IND_EXPORTPushed(app, event)
            app.commu.Value = ''; app.Lamp.Color = 'y'; pause(0.0001);
            
            tpfirst = app.Selected_Timepoint_First.Value;
            tplast = app.Selected_Timepoint_Last.Value;
            
            SourceF = app.EditField_filepath.Value;
            filetype = 4;
            fileformat = app.ListBox_Filetype.Value;
            row = app.Selected_Row.Value;
            col = app.Selected_Column.Value;
            field = app.Selected_Field.Value;
            plane = app.Selected_Plane.Value;
            channel = app.Selected_Channel.Value;
            tp = app.Selected_IND_Timepoint.Value;
            imlocation = [ row col field plane channel ];
            templateinfo = loadimageinfo(app,filetype,fileformat,imlocation,tp,SourceF);
            imwidth = templateinfo.Width;
            imheight = templateinfo.Height;
            
            id = app.Spinner_SelectedCell.Value;
            
            if isempty(app.img)
                app.commu.Value = 'No input images.'; pause(0.0001);
                return;
            end
            
            app.commu.Value = 'Generating MATLAB movie...'; pause(0.0001);
            
            if app.Button_TIFfile.Value == 1
                JInd = 1;
                indfolder = fullfile(SourceF,['myMov_r' num2str(row) 'c' num2str(col) 'f' num2str(field) 'ch' num2str(channel) '_id' num2str(id)]);
                mkdir(indfolder);
            else
                JInd = 0;
            end
            
            aviobj = VideoWriter(fullfile(SourceF,['myMov_r' num2str(row) 'c' num2str(col) 'f' num2str(field) 'ch' num2str(channel) '_id' num2str(id) '.avi']));
            aviobj.FrameRate = app.EditField_FPS.Value;
            aviobj.Quality = 100;
            open(aviobj);
            f1 = figure(1);
            axis image;
            set(gca,'nextplot','replacechildren');
            set(gcf,'Renderer','zbuffer');
            
            for tp = tpfirst:tplast
                app.commu.Value = sprintf('Generating MATLAB movie (%d %%)...',round(tp/tplast*100)); pause(0.0001);
                
                cellsize = app.EditField_BOX.Value;
                
                xL=max(app.cellpath{tp}(id,1)-cellsize,1);
                xR=min(app.cellpath{tp}(id,1)+cellsize,imwidth);
                yL=max(app.cellpath{tp}(id,2)-cellsize,1);
                yR=min(app.cellpath{tp}(id,2)+cellsize,imheight);
                pixelsreg = {[yL yR], [xL xR]};
                
                app.img = loadimage2(app,filetype,fileformat,imlocation,tp,pixelsreg,SourceF);
                
                figure(1),imshow(imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]),'InitialMagnification',50); hold on;
                
                drawnow;
                F = getframe(gca);
                writeVideo(aviobj,F);
                
                if JInd == 1
                    J = imadjust((app.img),[app.EditField_Thres_Min.Value app.EditField_Thres_Max.Value],[0 1]);
                    indname = ['myID' num2str(id) '_r' num2str(row) 'c' num2str(col) 'f' num2str(field) 't' num2str(tp) 'ch' num2str(channel) '.tif'];
                    imwrite(J,fullfile(indfolder,indname));
                end
                
            end
            
            close(aviobj);
            close(f1);
            
            app.commu.Value = ['Done generating: myMov_r' num2str(row) 'c' num2str(col) 'f' num2str(col) 'ch' num2str(channel) '_id' num2str(id) '.avi']; app.Lamp.Color = 'g'; pause(0.5); app.Lamp.Color = [0.9 0.9 0.9];
        end

        % Value changed function: Button_TIFfile
        function Button_TIFfileValueChanged(app, event)
            value = app.Button_TIFfile.Value;
            if value == 1
                app.Button_TIFfile.FontColor = [ 1.00,1.00,1.00 ];
                app.Button_TIFfile.BackgroundColor = [ 0.00 0.00 1.00 ];
            else
                app.Button_TIFfile.FontColor = [ 0.00,0.00,0.00 ];
                app.Button_TIFfile.BackgroundColor = [ 0.96,0.96,0.96 ];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CellTrackingVideoLabel = uilabel(app.UIFigure);
            app.CellTrackingVideoLabel.Text = 'CellTracking Video';
            app.Text02 = uilabel(app.UIFigure);
            app.Text02.Text = {'Version. 14-02-2020'; '@ 2020 Kriengkrai Phongkitkarun. All rights reserved.'};
            app.commu = uieditfield(app.UIFigure, 'text');
            app.Slider_Frame = uislider(app.UIFigure);
            app.Slider_Frame.Limits = [1 10];
            app.Slider_Frame.MajorTicks = [];
            app.Slider_Frame.ValueChangedFcn = createCallbackFcn(app, @Slider_FrameValueChanged, true);
            app.Slider_Frame.ValueChangingFcn = createCallbackFcn(app, @Slider_FrameValueChanging, true);
            app.Slider_Frame.MinorTicks = [];
            app.Slider_Frame.Value = 1;
            app.ProcessPanel = uipanel(app.UIFigure);
            app.Button_Play = uibutton(app.ProcessPanel, 'push');
            app.Button_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_PlayPushed, true);
            app.Button_Play.Icon = fullfile(pathToMLAPP, 'icons', 'play-icon.png');
            app.Button_Play.Text = '';
            app.Button_Pause = uibutton(app.ProcessPanel, 'push');
            app.Button_Pause.ButtonPushedFcn = createCallbackFcn(app, @Button_PausePushed, true);
            app.Button_Pause.Icon = fullfile(pathToMLAPP, 'icons', 'pause-icon.png');
            app.Button_Pause.Text = '';
            app.Button_Previous = uibutton(app.ProcessPanel, 'push');
            app.Button_Previous.ButtonPushedFcn = createCallbackFcn(app, @Button_PreviousPushed, true);
            app.Button_Previous.Icon = fullfile(pathToMLAPP, 'icons', 'rewind-icon.png');
            app.Button_Previous.Text = '';
            app.Button_Next = uibutton(app.ProcessPanel, 'push');
            app.Button_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_NextPushed, true);
            app.Button_Next.Icon = fullfile(pathToMLAPP, 'icons', 'forward-icon.png');
            app.Button_Next.Text = '';
            app.Button_First = uibutton(app.ProcessPanel, 'push');
            app.Button_First.ButtonPushedFcn = createCallbackFcn(app, @Button_FirstPushed, true);
            app.Button_First.Icon = fullfile(pathToMLAPP, 'icons', 'first-track-icon.png');
            app.Button_First.Text = '';
            app.Button_Last = uibutton(app.ProcessPanel, 'push');
            app.Button_Last.ButtonPushedFcn = createCallbackFcn(app, @Button_LastPushed, true);
            app.Button_Last.Icon = fullfile(pathToMLAPP, 'icons', 'last-track-icon.png');
            app.Button_Last.Text = '';
            app.FileProcessingPanel = uipanel(app.UIFigure);
            app.FileProcessingPanel.Title = 'File Processing';
            app.Lamp = uilamp(app.FileProcessingPanel);
            app.EditField_filepath = uieditfield(app.FileProcessingPanel, 'text');
            app.EditField_filepath.Editable = 'off';
            app.FileTypeLabel = uilabel(app.FileProcessingPanel);
            app.FileTypeLabel.Text = 'File Type';
            app.ListBox_Filetype = uilistbox(app.FileProcessingPanel);
            app.ListBox_Filetype.Items = {'Columbus Export'};
            app.ListBox_Filetype.ItemsData = {'%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif'};
            app.ListBox_Filetype.Value = '%03.0f%03.0f-%u-%03.0f%03.0f%03.0f.tif';
            app.RowEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.RowEditFieldLabel.Text = 'Row';
            app.Selected_Row = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Row.Limits = [1 Inf];
            app.Selected_Row.Editable = 'off';
            app.Selected_Row.Value = 1;
            app.ColumnEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.ColumnEditFieldLabel.Text = 'Column';
            app.Selected_Column = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Column.Limits = [1 Inf];
            app.Selected_Column.Editable = 'off';
            app.Selected_Column.Value = 1;
            app.FieldEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.FieldEditFieldLabel.Text = 'Field';
            app.Selected_Field = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Field.Limits = [1 Inf];
            app.Selected_Field.Editable = 'off';
            app.Selected_Field.Value = 1;
            app.PlaneEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.PlaneEditFieldLabel.Text = 'Plane';
            app.Selected_Plane = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Plane.Limits = [1 Inf];
            app.Selected_Plane.Editable = 'off';
            app.Selected_Plane.Value = 1;
            app.ChannelEditFieldLabel = uilabel(app.FileProcessingPanel);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.Selected_Channel = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Channel.Limits = [1 Inf];
            app.Selected_Channel.ValueChangedFcn = createCallbackFcn(app, @Selected_ChannelValueChanged, true);
            app.Selected_Channel.Value = 1;
            app.FirstTPLabel = uilabel(app.FileProcessingPanel);
            app.FirstTPLabel.Text = 'First TP';
            app.Selected_Timepoint_First = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Timepoint_First.Limits = [1 Inf];
            app.Selected_Timepoint_First.Editable = 'off';
            app.Selected_Timepoint_First.Value = 1;
            app.LastTPLabel = uilabel(app.FileProcessingPanel);
            app.LastTPLabel.Text = 'Last TP';
            app.Selected_Timepoint_Last = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Timepoint_Last.Limits = [1 Inf];
            app.Selected_Timepoint_Last.Editable = 'off';
            app.Selected_Timepoint_Last.Value = 10;
            app.CurrentLabel = uilabel(app.FileProcessingPanel);
            app.CurrentLabel.Text = 'Current';
            app.Selected_Timepoint = uieditfield(app.FileProcessingPanel, 'numeric');
            app.Selected_Timepoint.Limits = [1 Inf];
            app.Selected_Timepoint.ValueChangedFcn = createCallbackFcn(app, @Selected_TimepointValueChanged, true);
            app.Selected_Timepoint.Value = 1;
            app.DisplaythresholdLabel = uilabel(app.FileProcessingPanel);
            app.DisplaythresholdLabel.Text = 'Display threshold';
            app.EditField_Thres_Min = uieditfield(app.FileProcessingPanel, 'numeric');
            app.EditField_Thres_Min.Limits = [0 Inf];
            app.EditField_Thres_Min.ValueChangedFcn = createCallbackFcn(app, @EditField_Thres_MinValueChanged, true);
            app.EditField_Thres_Max = uieditfield(app.FileProcessingPanel, 'numeric');
            app.EditField_Thres_Max.Limits = [0 Inf];
            app.EditField_Thres_Max.ValueChangedFcn = createCallbackFcn(app, @EditField_Thres_MaxValueChanged, true);
            app.EditField_Thres_Max.Value = 1;
            app.Button_AutoThres = uibutton(app.FileProcessingPanel, 'push');
            app.Button_AutoThres.ButtonPushedFcn = createCallbackFcn(app, @Button_AutoThresPushed, true);
            app.Button_AutoThres.Text = 'Auto';
            app.EditField_CellSize = uieditfield(app.FileProcessingPanel, 'numeric');
            app.EditField_CellSize.Limits = [0 Inf];
            app.EditField_CellSize.Value = 15;
            app.CellSizeLabel = uilabel(app.FileProcessingPanel);
            app.CellSizeLabel.Text = 'Cell Size';
            app.EditField_CellDilateSize = uieditfield(app.FileProcessingPanel, 'numeric');
            app.EditField_CellDilateSize.Limits = [0 Inf];
            app.EditField_CellDilateSize.Value = 5;
            app.CellDilateSizeLabel = uilabel(app.FileProcessingPanel);
            app.CellDilateSizeLabel.Text = 'Cell Dilate Size';
            app.Button_AlwayThres = uibutton(app.FileProcessingPanel, 'state');
            app.Button_AlwayThres.ValueChangedFcn = createCallbackFcn(app, @Button_AlwayThresValueChanged, true);
            app.Button_AlwayThres.Text = '';
            app.MaskGeneratingPanel = uipanel(app.UIFigure);
            app.MaskGeneratingPanel.Title = 'Mask Generating';
            app.Button_RUN = uibutton(app.MaskGeneratingPanel, 'push');
            app.Button_RUN.ButtonPushedFcn = createCallbackFcn(app, @Button_RUNPushed, true);
            app.Button_RUN.Icon = fullfile(pathToMLAPP, 'icons', 'rocket.png');
            app.Button_RUN.Text = 'RUN';
            app.SegmentedCHEditFieldLabel = uilabel(app.MaskGeneratingPanel);
            app.SegmentedCHEditFieldLabel.Text = 'Segmented CH';
            app.EditField_SegmentCH = uieditfield(app.MaskGeneratingPanel, 'text');
            app.EditField_SegmentCH.Value = '1';
            app.maskingCHEditFieldLabel = uilabel(app.MaskGeneratingPanel);
            app.maskingCHEditFieldLabel.Text = 'masking CH';
            app.EditField_MaskCH = uieditfield(app.MaskGeneratingPanel, 'text');
            app.EditField_MaskCH.Value = '1,2';
            app.VideoPanel = uipanel(app.UIFigure);
            app.VideoPanel.Title = 'Video';
            app.TimeStampLabel = uilabel(app.VideoPanel);
            app.TimeStampLabel.Text = 'Time Stamp                    :               :           ';
            app.CheckBox_TimeStamp = uicheckbox(app.VideoPanel);
            app.CheckBox_TimeStamp.Text = '';
            app.CheckBox_TimeStamp.Value = true;
            app.CheckBox_CellNumber = uicheckbox(app.VideoPanel);
            app.CheckBox_CellNumber.Text = 'Cell Number';
            app.CheckBox_CellNumber.Value = true;
            app.FramerateEditFieldLabel = uilabel(app.VideoPanel);
            app.FramerateEditFieldLabel.Text = 'Frame rate';
            app.EditField_FPS = uieditfield(app.VideoPanel, 'numeric');
            app.EditField_FPS.Limits = [0 Inf];
            app.EditField_FPS.Value = 8;
            app.Button_GENERATE = uibutton(app.VideoPanel, 'push');
            app.Button_GENERATE.ButtonPushedFcn = createCallbackFcn(app, @Button_GENERATEPushed, true);
            app.Button_GENERATE.Icon = fullfile(pathToMLAPP, 'icons', 'cinema.png');
            app.Button_GENERATE.Text = 'GENERATE';
            app.HREditFieldLabel = uilabel(app.VideoPanel);
            app.HREditFieldLabel.Text = 'HR';
            app.EditField_HR = uieditfield(app.VideoPanel, 'numeric');
            app.EditField_HR.ValueDisplayFormat = '%.0f';
            app.MINEditFieldLabel = uilabel(app.VideoPanel);
            app.MINEditFieldLabel.Text = 'MIN';
            app.EditField_MIN = uieditfield(app.VideoPanel, 'numeric');
            app.EditField_MIN.ValueDisplayFormat = '%.0f';
            app.EditField_MIN.Value = 30;
            app.SECEditFieldLabel = uilabel(app.VideoPanel);
            app.SECEditFieldLabel.Text = 'SEC';
            app.EditField_SEC = uieditfield(app.VideoPanel, 'numeric');
            app.EditField_SEC.ValueDisplayFormat = '%.0f';
            app.IndividualCellPanel = uipanel(app.UIFigure);
            app.IndividualCellPanel.Title = 'Individual Cell';
            app.UIAxes_IND = uiaxes(app.IndividualCellPanel);
            app.UIAxes_IND.XTick = [];
            app.UIAxes_IND.YTick = [];
            app.SelectnoSpinnerLabel = uilabel(app.IndividualCellPanel);
            app.SelectnoSpinnerLabel.Text = 'Select no.';
            app.Spinner_SelectedCell = uispinner(app.IndividualCellPanel);
            app.Spinner_SelectedCell.Limits = [1 Inf];
            app.Spinner_SelectedCell.ValueChangedFcn = createCallbackFcn(app, @Spinner_SelectedCellValueChanged, true);
            app.Spinner_SelectedCell.Value = 1;
            app.Button_IND_Play = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_Play.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_PlayPushed, true);
            app.Button_IND_Play.Icon = 'play-icon.png';
            app.Button_IND_Play.Text = '';
            app.Button_IND_Pause = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_Pause.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_PausePushed, true);
            app.Button_IND_Pause.Icon = 'pause-icon.png';
            app.Button_IND_Pause.Text = '';
            app.Button_IND_Previous = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_Previous.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_PreviousPushed, true);
            app.Button_IND_Previous.Icon = 'rewind-icon.png';
            app.Button_IND_Previous.Text = '';
            app.Button_IND_Next = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_Next.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_NextPushed, true);
            app.Button_IND_Next.Icon = 'forward-icon.png';
            app.Button_IND_Next.Text = '';
            app.Button_IND_First = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_First.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_FirstPushed, true);
            app.Button_IND_First.Icon = 'first-track-icon.png';
            app.Button_IND_First.Text = '';
            app.Button_IND_Last = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_Last.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_LastPushed, true);
            app.Button_IND_Last.Icon = 'last-track-icon.png';
            app.Button_IND_Last.Text = '';
            app.CurrentEditFieldLabel = uilabel(app.IndividualCellPanel);
            app.CurrentEditFieldLabel.Text = 'Current';
            app.Selected_IND_Timepoint = uieditfield(app.IndividualCellPanel, 'numeric');
            app.Selected_IND_Timepoint.Limits = [1 Inf];
            app.Selected_IND_Timepoint.ValueChangedFcn = createCallbackFcn(app, @Selected_IND_TimepointValueChanged, true);
            app.Selected_IND_Timepoint.Value = 1;
            app.Button_IND_EXPORT = uibutton(app.IndividualCellPanel, 'push');
            app.Button_IND_EXPORT.ButtonPushedFcn = createCallbackFcn(app, @Button_IND_EXPORTPushed, true);
            app.Button_IND_EXPORT.Icon = fullfile(pathToMLAPP, 'icons', 'export.png');
            app.Button_IND_EXPORT.Text = 'EXPORT';
            app.BOXEditFieldLabel = uilabel(app.IndividualCellPanel);
            app.BOXEditFieldLabel.Text = 'BOX';
            app.EditField_BOX = uieditfield(app.IndividualCellPanel, 'numeric');
            app.EditField_BOX.Limits = [0 Inf];
            app.EditField_BOX.ValueChangedFcn = createCallbackFcn(app, @EditField_BOXValueChanged, true);
            app.EditField_BOX.Value = 30;
            app.Button_TIFfile = uibutton(app.IndividualCellPanel, 'state');
            app.Button_TIFfile.ValueChangedFcn = createCallbackFcn(app, @Button_TIFfileValueChanged, true);
            app.Button_TIFfile.Text = 'TIF file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dialogcelltracking(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end